classdef CK_Tuner_EphysSetup_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CommunicationPanel         matlab.ui.container.Panel
        comm_2pPC_lamp             matlab.ui.control.Lamp
        comm_2pPC_connect          matlab.ui.control.Button
        pPCEditFieldLabel          matlab.ui.control.Label
        comm_2pPC_com              matlab.ui.control.EditField
        comm_Motion_lamp           matlab.ui.control.Lamp
        comm_Motion_connect        matlab.ui.control.Button
        MotionEditFieldLabel       matlab.ui.control.Label
        comm_Motion_com            matlab.ui.control.EditField
        LogPanel                   matlab.ui.container.Panel
        SubjectTextAreaLabel       matlab.ui.control.Label
        SubjectTextArea            matlab.ui.control.TextArea
        DateTextAreaLabel          matlab.ui.control.Label
        DateTextArea               matlab.ui.control.TextArea
        SavepathTextAreaLabel      matlab.ui.control.Label
        SavepathTextArea           matlab.ui.control.TextArea
        BrowseButton               matlab.ui.control.Button
        SessionEditFieldLabel      matlab.ui.control.Label
        SessionEditField           matlab.ui.control.NumericEditField
        StimulussetupPanel         matlab.ui.container.Panel
        StimdurationLabel          matlab.ui.control.Label
        StimdurationEditField      matlab.ui.control.NumericEditField
        sLabel                     matlab.ui.control.Label
        ITIEditFieldLabel          matlab.ui.control.Label
        ITI1                       matlab.ui.control.NumericEditField
        toLabel                    matlab.ui.control.Label
        ITI2                       matlab.ui.control.NumericEditField
        CPDEditFieldLabel          matlab.ui.control.Label
        CPDEditField               matlab.ui.control.NumericEditField
        CPSEditFieldLabel          matlab.ui.control.Label
        CPSEditField               matlab.ui.control.NumericEditField
        ApSizeEditFieldLabel       matlab.ui.control.Label
        ApSizeEditField            matlab.ui.control.NumericEditField
        ApLocXEditFieldLabel       matlab.ui.control.Label
        ApLocXEditField            matlab.ui.control.NumericEditField
        ApLocYEditFieldLabel       matlab.ui.control.Label
        ApLocYEditField            matlab.ui.control.NumericEditField
        PairedoriEditFieldLabel_4  matlab.ui.control.Label
        PairedoriEditFieldLabel_7  matlab.ui.control.Label
        SetButton                  matlab.ui.control.Button
        DegToPxEditFieldLabel      matlab.ui.control.Label
        DegToPxEditField           matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel  matlab.ui.control.Label
        RepetitionsEditField       matlab.ui.control.NumericEditField
        ExecutioncontrolPanel      matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        TrialEditFieldLabel        matlab.ui.control.Label
        TrialEditField             matlab.ui.control.NumericEditField
        ofEditFieldLabel           matlab.ui.control.Label
        ofEditField                matlab.ui.control.NumericEditField
    end


    methods (Access = public)
       
        function show_gray(app)
            global Par
            Screen('FillRect', Par.PTB.window,[128/256 128/256 128/256], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
        end
      
         function show_black(app)
            global Par
            Screen('FillRect', Par.PTB.window,[0 0 0], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
         end
         
%         function showDG(app, ori)
%             global Par
%             global texDG dstRect
%             global t_abs0
%             
%             phase = 0;
%             freq = Par.CPD / Par.DegToPx; % convert CPD into pixel
%             contrast = 0.5;
%             num_cycles = Par.StimDur * Par.CPS;
%             d_phase = (num_cycles * 360)/(60*Par.StimDur);
%             firstflip_flag = 1;
%             ori = ori + 90; % correct for PTB drift direction
%             t_start = GetSecs;
%             
%             
%             while GetSecs < t_start + Par.StimDur
%                 
%                   phase = phase + d_phase;
%                   Screen('DrawTexture', Par.PTB.window, texDG, [], dstRect, ori, [], [], [], [], [], [phase, freq, contrast, 0]);
%                   Screen('Flip', Par.PTB.window);
%                   
%                   if firstflip_flag == 1 && Par.das == 1 % DAS bit after 1st flip
%                       dasbita(1,1)
%                       firstflip_flag = 0;
%                   end
%             end
%             
%             if Par.das == 1 % DAS bit low after LAST flip
%                 dasbita(1,0)
%             end
%             
%             show_gray(app);
%         end

    function showCK(app)
        global Par texCK0 texCK1 dstRect
        
        FlipTimeSecs = Par.StimDur;
        FlipTimeFrames = round(FlipTimeSecs / Par.PTB.ifi);
        
        filterMode = 0;
        
        if Par.das == 1
            dasbita(1,1);
        end
        
        Screen('DrawTextures', Par.PTB.window, texCK0, [],...
            dstRect, 0, filterMode);
        tic1 = tic;
        vbl = Screen('Flip', Par.PTB.window);
        frameCounter = 1;
        while frameCounter < FlipTimeFrames
            Screen('DrawTextures', Par.PTB.window, texCK0, [],...
                dstRect, 0, filterMode);
            vbl = Screen('Flip',Par.PTB.window, vbl+0.5*Par.PTB.ifi);
            frameCounter = frameCounter + 1;
        end
        
        Screen('DrawTextures', Par.PTB.window, texCK1, [],...
            dstRect, 0, filterMode);
        vbl = Screen('Flip',Par.PTB.window);
        frameCounter = 1;
        while frameCounter < FlipTimeFrames
            Screen('DrawTextures', Par.PTB.window, texCK1, [],...
                dstRect, 0, filterMode);
            vbl = Screen('Flip',Par.PTB.window, vbl+0.5*Par.PTB.ifi);
            frameCounter = frameCounter + 1;
        end
        
        Par.diagnostics.t_stimDur(end+1) = toc(tic1);
        
        Screen('FillRect', Par.PTB.window, [0.5 0.5 0.5]);
        vbl = Screen('Flip',Par.PTB.window);
        
        if Par.das == 1
            dasbita(1,0);
        end
        
    end
         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Par
            
            openobj = instrfind;
            if ~isempty(openobj)
                fclose(openobj); % close all serial ports
            end
            Par.dbfields = getdbfields('VC');
            app.SubjectTextArea.Value = Par.dbfields.subject;
            app.DateTextArea.Value = Par.dbfields.date;

            %Initialize DAS
            try
                dasinit(0);
                Par.das = 1;
%                 dasbit(0,0)
            catch ME
                Par.das = 0;
                disp('No DAS available')
            end
            
            % Initialize PTB
            PsychDefaultSetup(2);
            Par.PTB.screenNumber = 2;
            try 
                load('C:\MyGammaTable.mat',"gammaTable");
                Screen('LoadNormalizedGammaTable', Par.PTB.screenNumber, gammaTable*[1 1 1]);
            catch ME
                waitfor(msgbox('Unable to performa gamma correction! Make sure MyGammaTable.mat is under C:\ and run the program again!'));
            end
            [Par.PTB.window, Par.PTB.windowRect] = PsychImaging('OpenWindow', Par.PTB.screenNumber, 128/256);
            [Par.PTB.screenXpixels, Par.PTB.screenYpixels] = Screen('WindowSize', Par.PTB.window);
            [Par.PTB.xCenter, Par.PTB.yCenter] = RectCenter(Par.PTB.windowRect);
%             Screen('BlendFunction', Par.PTB.window, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            Par.PTB.ifi = Screen('GetFlipInterval', Par.PTB.window);
            
            % Timer object for background PTB execution
%              app.myTimer = timer('ExecutionMode',"singleShot",'TimerFcn',@(obj, event)showDG(app,ori));
            
            % Book-keeping vars
            Par.g2p = 0;
            Par.set = 0;
            Par.diagnostics.t_stimDur = [];
            Par.diagnostics.t_loop = [];
          
         
        end

        % Button pushed function: comm_2pPC_connect
        function comm_2pPC_connectButtonPushed(app, event)
            global Par
            Par.Lcon = [];
            try
                % G2P = '192.87.10.153';
                G2P = resolvehost(app.comm_2pPC_com.Value,'address');
                Par.Lcon = udp(G2P, 'RemotePort', 7000);
                Par.Lhost = udp('Localhost', 'LocalPort', 4000, 'BytesAvailableFcn', @udp_cb);
                fopen(Par.Lhost);
                app.comm_2pPC_lamp.Color = [0 1 0];
                Par.g2p = 1;
            catch
                disp('No UDP connection with Scanner system (G2P) possible')
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global Par
            global t_abs0
            global trials
        
            % ================ Housekeeping =====================
            
            % Check if savepath exists
            if ~isfield(Par,'savepath')
                error('Savepath is not defined!')
            end
            %%
            
            % Check if stimulus are set
            if Par.set == 0
                error('Stimulus are not set!')
            end
            %%
            
            % Make all trials
%             Par.ori_list = unique(str2num(app.OrientationsEditField.Value))'; % here "str2double" will ignore comma in list!
            Par.rep = app.RepetitionsEditField.Value;
%             trials = repmat(Par.ori_list,Par.rep,1);
%             trials = trials(randperm(size(trials,1))); % randomize
%             trials = trials(randperm(size(trials,1))); % randomize again
            
            
            % Setting up and start SB
            if Par.g2p == 1
                if ~isempty(Par.Lcon) && strcmp(get(Par.Lcon, 'Status'), 'closed')
                    fopen(Par.Lcon);
                end
                fprintf(Par.Lcon,['A' Par.dbfields.subject]); % set animal id field
                fprintf(Par.Lcon,['U' Par.dbfields.date]); % set date
                fprintf(Par.Lcon,['E ' sprintf('%03d', app.SessionEditField.Value)]); % set session num 
                fprintf(Par.Lcon,'G'); %start grabbing
                pause(10); % short pause to let the scope initializes
            else
                disp('---------------No G2P!---------------')
            end

            
            % Initialize some vars
            step = 0;
            trl = 0;
            
            Par.run = 1;
            t_abs0 = tic;
            
            app.TrialEditField.Value = 0;
            app.ofEditField.Value = Par.rep;
            
            % ================ Game =====================
                while Par.run == 1
                    
                    m1 = tic; % marker m1; counter for one while loop. 
                    
                    % Let it run for 5 sec before the first trl
                    if step == 0
                        if trl == 0 && toc(t_abs0) < 5
                            step = 0;
                        else
                            step = 1;
                        end
                    end
                        
                    % Stimulus starts
                    if step == 1
                        
                        trl = trl + 1;
                        
                        app.TrialEditField.Value = trl;
                        
%                         if isfield(Par, 'Lcon') % send trial ID to SB
%                             fprintf(Par.Lcon,['M' num2str(trials(trl))]); 
%                         end 
                                               
                        % Deliver stimulus
                        disp(num2str(trl));
                        app.TrialEditField.Value = app.TrialEditField.Value + 1;
                        showCK(app)
                            
                        t_endOfSti = tic;
                        step = 2;
                        iti = datasample([Par.ITI1:0.5:Par.ITI2],1);
                                               
                    end
                    
                    if step == 2 && toc(t_endOfSti) > iti
                        if trl == Par.rep
                            f = warndlg('All stimuli delivered');
                            StopButtonPushed(app);
                        else 
                            step = 1;
                        end
                    end


                    lt = toc(m1);
                    Par.diagnostics.t_loop(end+1,:) = [lt step];
                    
                    
                    drawnow limitrate; %this must run every loop otherwise it lags!

                end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            global Par
            
            % Stop trial run
            Par.run = 0;

            pause(1); %extra scanning time before ending session
            if Par.g2p == 1
                fprintf(Par.Lcon,'S'); %stop imaging
                fclose(Par.Lcon);
            end
            
            %%
            
            % File name
            FN = char(strcat(app.SubjectTextArea.Value, '_', app.DateTextArea.Value, '_', num2str(app.SessionEditField.Value)));
            %%
            
            % File name 
            json.version = '1.0';
            json.project = Par.dbfields.project;
            json.dataset = Par.dbfields.dataset;  
            json.date = Par.dbfields.date;
            json.subject = Par.dbfields.subject;
            json.investigator = Par.dbfields.investigator;
            json.setup = Par.dbfields.setup;
            json.condition = Par.dbfields.condition;   %not essential
            json.stimulus = Par.dbfields.stimulus; %name (str) of function
            json.logfile = [FN '_log']; %name for your logfile
            json.session = app.SessionEditField.Value;
            % json.script = thisRecord.script;
            json.display = Par.PTB;
            savejson('', json, [FN '_session.json']);
            %%
            
            % Save log (path is par.savepath)
                uisave({'Par'}, [FN '_log']);
            %%
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            global Par
            global texCK0 texCK1 dstRect
            
            Par.StimDur = app.StimdurationEditField.Value;
            Par.ITI1 = app.ITI1.Value;
            Par.ITI2 = app.ITI2.Value;
            Par.CPD = app.CPDEditField.Value;
            Par.CPS = app.CPSEditField.Value;
            Par.ApSize = app.ApSizeEditField.Value;
            Par.ApLocX = app.ApLocXEditField.Value;
            Par.ApLocY = app.ApLocYEditField.Value;
            Par.DegToPx = app.DegToPxEditField.Value;
            
%             % Build DG texture
%             support = 2000; % 2000px
%             ApSizePx = Par.ApSize * Par.DegToPx;
%             texDG = CreateProceduralSquareWaveGrating(Par.PTB.window, support, support, [.5 .5 .5 0], ApSizePx/2);

            % Build CK textures
            checkerboard = repmat(eye(2), 50, 50);
            texCK0 = Screen('MakeTexture', Par.PTB.window, checkerboard);
            texCK1 = Screen('MakeTexture', Par.PTB.window, 1-checkerboard);
            [s1, s2] = size(checkerboard);
            dstRect = [0 0 s1 s2] .* Par.DegToPx .* ((1/Par.CPD)/2);
            dstRect = CenterRectOnPointd(dstRect, Par.PTB.xCenter, Par.PTB.yCenter);

            % Build spatial offset dstRect for the Ap
%             xvals = linspace(-0.8667*Par.PTB.screenXpixels/2,0.8667*Par.PTB.screenXpixels/2,16); % based on quickRFmap
%             yvals = linspace(-0.8182*Par.PTB.screenYpixels/2,0.8182*Par.PTB.screenYpixels/2,12); % based on quickRFmap
%             xc = xvals(Par.ApLocX)+Par.PTB.screenXpixels/2;
%             yc = yvals(Par.ApLocY)+Par.PTB.screenYpixels/2;
%             dstRect = CenterRectOnPoint(Screen('Rect', texDG), xc, yc);

            Par.set = 1;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SavepathTextArea.Value = uigetdir;
            global Par
            Par.savepath = app.SavepathTextArea.Value;
            cd(char(Par.savepath))
        end

        % Button pushed function: comm_Motion_connect
        function comm_Motion_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_Motion = serial(app.comm_Motion_com.Value, 'Baudrate', 115200);
                fopen(Par.comm_Motion);
                app.comm_Motion_lamp.Color = [0 1 0];
                Par.Motion = 1;
            catch ME
                waitfor(msgbox('Error! Check port number.'));
                Par.Motion = 0;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CommunicationPanel = uipanel(app.UIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.comm_2pPC_lamp = uilamp(app.CommunicationPanel);
            app.comm_2pPC_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_2pPC_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_2pPC_connectButtonPushed, true);
            app.comm_2pPC_connect.Enable = 'off';
            app.comm_2pPC_connect.Text = 'Connect';
            app.pPCEditFieldLabel = uilabel(app.CommunicationPanel);
            app.pPCEditFieldLabel.Text = '2p PC';
            app.comm_2pPC_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_2pPC_com.Value = 'G2P';
            app.comm_Motion_lamp = uilamp(app.CommunicationPanel);
            app.comm_Motion_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Motion_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Motion_connectButtonPushed, true);
            app.comm_Motion_connect.Enable = 'off';
            app.comm_Motion_connect.Text = 'Connect';
            app.MotionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.comm_Motion_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Motion_com.Value = 'com7';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.SubjectTextAreaLabel = uilabel(app.LogPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.LogPanel);
            app.SubjectTextArea.Editable = 'off';
            app.DateTextAreaLabel = uilabel(app.LogPanel);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.LogPanel);
            app.DateTextArea.Editable = 'off';
            app.SavepathTextAreaLabel = uilabel(app.LogPanel);
            app.SavepathTextAreaLabel.Text = 'Save path';
            app.SavepathTextArea = uitextarea(app.LogPanel);
            app.SavepathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.LogPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LogPanel, 'numeric');
            app.SessionEditField.Value = 1;
            app.StimulussetupPanel = uipanel(app.UIFigure);
            app.StimulussetupPanel.Title = 'Stimulus setup';
            app.StimdurationLabel = uilabel(app.StimulussetupPanel);
            app.StimdurationLabel.Text = 'Stim. duration';
            app.StimdurationEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.StimdurationEditField.Value = 1;
            app.sLabel = uilabel(app.StimulussetupPanel);
            app.sLabel.Text = 's';
            app.ITIEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITI1 = uieditfield(app.StimulussetupPanel, 'numeric');
            app.ITI1.Value = 3;
            app.toLabel = uilabel(app.StimulussetupPanel);
            app.toLabel.Text = 'to';
            app.ITI2 = uieditfield(app.StimulussetupPanel, 'numeric');
            app.ITI2.Value = 5;
            app.CPDEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.CPDEditFieldLabel.Text = 'CPD';
            app.CPDEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.CPDEditField.Value = 0.04;
            app.CPSEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.CPSEditFieldLabel.Enable = 'off';
            app.CPSEditFieldLabel.Text = 'CPS';
            app.CPSEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.CPSEditField.Enable = 'off';
            app.CPSEditField.Value = 2;
            app.ApSizeEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.ApSizeEditFieldLabel.Text = 'ApSize';
            app.ApSizeEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.ApSizeEditField.Value = 1500;
            app.ApLocXEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.ApLocXEditFieldLabel.Text = 'ApLocX';
            app.ApLocXEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.ApLocXEditField.Value = 8;
            app.ApLocYEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.ApLocYEditFieldLabel.Text = 'ApLocY';
            app.ApLocYEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.ApLocYEditField.Value = 6;
            app.PairedoriEditFieldLabel_4 = uilabel(app.StimulussetupPanel);
            app.PairedoriEditFieldLabel_4.Text = 's (reversing at 1/2)';
            app.PairedoriEditFieldLabel_7 = uilabel(app.StimulussetupPanel);
            app.PairedoriEditFieldLabel_7.Text = 'deg';
            app.SetButton = uibutton(app.StimulussetupPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.DegToPxEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.DegToPxEditFieldLabel.Text = 'DegToPx';
            app.DegToPxEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.DegToPxEditField.Value = 9.21;
            app.RepetitionsEditFieldLabel = uilabel(app.StimulussetupPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.StimulussetupPanel, 'numeric');
            app.RepetitionsEditField.Value = 100;
            app.ExecutioncontrolPanel = uipanel(app.UIFigure);
            app.ExecutioncontrolPanel.Title = 'Execution control';
            app.StartButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrialEditFieldLabel = uilabel(app.ExecutioncontrolPanel);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.ofEditFieldLabel = uilabel(app.ExecutioncontrolPanel);
            app.ofEditFieldLabel.Text = 'of';
            app.ofEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Tuner_EphysSetup_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end