classdef ICMS_2P_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CommunicationPanel        matlab.ui.container.Panel
        comm_Lick_lamp            matlab.ui.control.Lamp
        comm_Lick_connect         matlab.ui.control.Button
        LickEditFieldLabel        matlab.ui.control.Label
        comm_Lick_com             matlab.ui.control.EditField
        comm_2pPC_lamp            matlab.ui.control.Lamp
        comm_2pPC_connect         matlab.ui.control.Button
        pPCEditFieldLabel         matlab.ui.control.Label
        comm_2pPC_com             matlab.ui.control.EditField
        comm_Motion_lamp          matlab.ui.control.Lamp
        comm_Motion_connect       matlab.ui.control.Button
        MotionEditFieldLabel      matlab.ui.control.Label
        comm_Motion_com           matlab.ui.control.EditField
        LogPanel                  matlab.ui.container.Panel
        SubjectTextAreaLabel      matlab.ui.control.Label
        SubjectTextArea           matlab.ui.control.TextArea
        DateTextAreaLabel         matlab.ui.control.Label
        DateTextArea              matlab.ui.control.TextArea
        SavepathTextAreaLabel     matlab.ui.control.Label
        SavepathTextArea          matlab.ui.control.TextArea
        BrowseButton              matlab.ui.control.Button
        SessionEditFieldLabel     matlab.ui.control.Label
        SessionEditField          matlab.ui.control.NumericEditField
        ICMSparametersPanel       matlab.ui.container.Panel
        PolarityDropDownLabel     matlab.ui.control.Label
        PolarityDropDown          matlab.ui.control.DropDown
        PhasewidthEditFieldLabel  matlab.ui.control.Label
        PhasewidthEditField       matlab.ui.control.EditField
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.EditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.EditField
        HzLabel                   matlab.ui.control.Label
        NrpulsesEditFieldLabel    matlab.ui.control.Label
        NrpulsesEditField         matlab.ui.control.EditField
        sLabel                    matlab.ui.control.Label
        ALabel                    matlab.ui.control.Label
        sLabel_2                  matlab.ui.control.Label
        IPDEditFieldLabel         matlab.ui.control.Label
        IPDEditField              matlab.ui.control.EditField
        ElectrodesEditFieldLabel  matlab.ui.control.Label
        ElectrodesEditField       matlab.ui.control.EditField
        ALRDropDownLabel          matlab.ui.control.Label
        ALRDropDown               matlab.ui.control.DropDown
        ALRelecEditFieldLabel     matlab.ui.control.Label
        ALRelecEditField          matlab.ui.control.EditField
        CereStimPanel             matlab.ui.container.Panel
        ConnButton                matlab.ui.control.Button
        DiscButton                matlab.ui.control.Button
        ApplyparametersButton     matlab.ui.control.Button
        ConnectionLampLabel       matlab.ui.control.Label
        ConnectionLamp            matlab.ui.control.Lamp
        StimDropDownLabel         matlab.ui.control.Label
        StimDropDown              matlab.ui.control.DropDown
        DeliveryPanel             matlab.ui.container.Panel
        ModeLabel                 matlab.ui.control.Label
        ModeDropDown              matlab.ui.control.DropDown
        ExecutioncontrolPanel     matlab.ui.container.Panel
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        StimButton                matlab.ui.control.Button
        NumberofstimsLabel        matlab.ui.control.Label
        Text_NumStim              matlab.ui.control.TextArea
        incshamLabel              matlab.ui.control.Label
        Text_NumSham              matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        function startSB(app)
            global Par
            
            if Par.g2p_conn == 1
                if ~isempty(Par.Lcon) && strcmp(get(Par.Lcon, 'Status'), 'closed')
                    fopen(Par.Lcon);
                end
                fprintf(Par.Lcon,['A' Par.dbfields.subject]); % set animal id field
                fprintf(Par.Lcon,['U' Par.dbfields.date]); % set date
                fprintf(Par.Lcon,['E ' sprintf('%03d', app.SessionEditField.Value)]); % set session num
                fprintf(Par.Lcon,'G'); %start grabbing
                pause(10); % short pause to let the scope initializes
            else
                disp('Running trials without 2P!')
            end
        end
        
        function startCS (app)
            global Par
            global stimulator
            
            % Stimulator connection
            stimulator = cerestim96();
            my_devices = stimulator.scanForDevices;
            pause(0.5);
            if isempty(my_devices)
                f = warndlg('No Cerestim found!');
                
            else
                stimulator.selectDevice(0); % 0 is the first Cerestim found by scanForDevices
                pause(0.5);
                stimulator.connect;
                pause(0.5);
                Par.CsConn = 1;
                app.ConnectionLamp.Color = [0 1 0];
            end
        end
        
        function discCS(app)
            global stimulator
            
            x = stimulator.isConnected();
            if ~x
                disp('There is nothing to disconnect!')
            else
                stimulator.disconnect;
                app.ConnectionLamp.Color = [1 0 0];
                disp('Stimulator disconnected.')
            end
            clear x;
        end
        
        function stimParApply(app)
            global wf
            global stimulator
            global Par
            
            % Load wf parameters
            wf.id = 1;
            if app.PolarityDropDown.Value == 'CF'
                wf.polarity = 1; %CF=1; AF=0;
            else
                wf.polarity = 0;
            end
            wf.nPulses = str2double(app.NrpulsesEditField.Value);
            wf.currAmp = str2double(app.AmplitudeEditField.Value);
            wf.elec = str2num(app.ElectrodesEditField.Value);
            wf.width = str2double(app.PhasewidthEditField.Value);
            wf.ipd = str2double(app.IPDEditField.Value);
            wf.freq = str2double(app.FrequencyEditField.Value);
            
            stimulator.setStimPattern('waveform',wf.id,...
            'polarity',wf.polarity,...
            'pulses',wf.nPulses,...
            'amp1',wf.currAmp,...
            'amp2',wf.currAmp,...
            'width1',wf.width,...
            'width2',wf.width,...
            'interphase',wf.ipd,...
            'frequency',wf.freq);
            stimulator.beginSequence;
                stimulator.beginGroup;
                    for i = size(wf.elec,2)
                        stimulator.autoStim(wf.elec(1,i),wf.id);
                    end
                stimulator.endGroup;
            stimulator.endSequence;
        end
         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Par
            openobj = instrfind;
            if ~isempty(openobj)
                fclose(openobj); % close all serial ports
            end
            Par.dbfields = getdbfields('VC');
            app.SubjectTextArea.Value = Par.dbfields.subject;
            app.DateTextArea.Value = Par.dbfields.date;
            
            %Initialize DAS
            try
                dasint(0);
                Par.das = 1;
            catch ME
                Par.das = 0;
                warndlg('No DAS available!')
            end
            
%             % Initialize PTB
%             PsychDefaultSetup(2);
%             Par.PTB.screenNumber = 2;
%             try 
%                 load('C:\MyGammaTable.mat',"gammaTable");
%             catch ME
%                 waitfor(msgbox('Unable to performa gamma correction! Make sure MyGammaTable.mat is under C:\ and run the program again!'));
%             end
%             Screen('LoadNormalizedGammaTable', Par.PTB.screenNumber, gammaTable*[1 1 1]);
%             Screen('OpenWindow',Par.PTB.screenNumber, 0.5);
%             % Screen('Preference', 'SkipSyncTests', 1);
%             [Par.PTB.window, Par.PTB.windowRect] = PsychImaging('OpenWindow', Par.PTB.screenNumber, [0 128 128]);
%             [Par.PTB.screenXpixels, Par.PTB.screenYpixels] = Screen('WindowSize', Par.PTB.window);
%             [Par.PTB.sourceFactorOld, Par.PTB.destinationFactorOld, Par.PTB.colorMaskOld] = Screen('Blendfunction', Par.PTB.window, [], [], [0 1 1 1]);
%             [Par.PTB.xCenter, Par.PTB.yCenter] = RectCenter(Par.PTB.windowRect);
%             warndlg('PTB initialized in no-red mode!')
% %             Screen('BlendFunction', Par.PTB.window, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            
            
            % Book-keeping vars
            Par.g2p = 0;
            Par.set = 0;
            Par.logsave = 0;
            Par.g2p_conn = 0;
            Par.lick = 0;


        end

        % Button pushed function: comm_Lick_connect
        function comm_Lick_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_Lick = serial(app.comm_Lick_com.Value, 'Baudrate', 115200);
                fopen(Par.comm_Lick);
                app.comm_Lick_lamp.Color = [0 1 0];
                Par.lick = 1;
            catch ME
                disp('Error! Check port number.');
                Par.lick = 0;
            end 
        end

        % Button pushed function: comm_Motion_connect
        function comm_Motion_connectPushed(app, event)
            global Par
            try
                Par.comm_Motion = serial(app.comm_Motion_com.Value, 'Baudrate', 9600);
                fopen(Par.comm_Motion);
                app.comm_Motion_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check port number.');
            end
        end

        % Button pushed function: comm_2pPC_connect
        function comm_2pPC_connectButtonPushed(app, event)
            global Par
            Par.Lcon = [];
            try
                % G2P = '192.87.10.153';
                G2P = resolvehost(app.comm_2pPC_com.Value,'address');
                Par.Lcon = udp(G2P, 'RemotePort', 7000);
                Par.Lhost = udp('Localhost', 'LocalPort', 4000, 'BytesAvailableFcn', @udp_cb);
                fopen(Par.Lhost);
                app.comm_2pPC_lamp.Color = [0 1 0];
                Par.g2p = 1;
            catch
                disp('No UDP connection with Scanner system (G2P) possible')
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SavepathTextArea.Value = uigetdir;
            global Par
            Par.savepath = app.SavepathTextArea.Value;
            cd(char(Par.savepath));
            Par.logsave = 1;
        end

        % Callback function
        function StartButtonPushed(app, event)
            global Par
            global passive
            global data
            global perfdata
            global fig1
            global time
            global trl
            global t_trl_abs t_lick_abs
            global licksen t_abs0
            global givestim

            Par.run = 1;
            time = [];
            
           
            % ================ Housekeeping =====================
                      
            
            % Start ScanBox
            startSB(app);
            
            
            % Housekeeping tasks
            data = zeros(trl_max,5); % [time trial_type NLDS outcome RT]
            trl = 0;
       
            % ================ Game =====================
                while Par.run == 1
                    m1 = tic; % marker m1; counter for one while loop. 
                    
                    if app.ModeDropDown.Value == 'M'
                        if givestim == 1
                            stimfcn;
                            trl = trl + 1;
                            givestim = 0;
                        end
                    else
                        
                    end
                     

                    app.Text_NumStim.Value = num2str(trl);

                    drawnow limitrate; %this must run every loop otherwise it lags!
                    xt8 = toc(m1);
                    time = [time; xt8];

                end 
                
              
        end

        % Callback function
        function InterjectButtonPushed(app, event)
            global passive
            passive = 1;
        end

        % Callback function
        function StopButtonPushed(app, event)
            global Par sound
            global data
            global perfdata
            global time
            global trl
            global fig1
            global t_trl_abs t_lick_abs t_abs0
            
            % Stop trial run
            Par.run = 0;
            %%
            
            % Close the sound port
            PsychPortAudio('Close',sound.pahandle);
            %%
                       
            % Prepare table for log file
            data_table = array2table(data(1:trl,:));
            data_table.Properties.VariableNames = {'DateTime', 'TrlType', 'NLDS', 'Outcome', 'RT'};
            perfdata.log = array2table(perfdata.log(1:trl,:));
            perfdata.log.Properties.VariableNames = {'TrialN','incPassive','Hit_c','Miss_c','CR_c','FA_c','HR_c','FAR_c','Dp_c','Hit_i','Miss_i','CR_i','FA_i','HR_i','FAR_i','DP_i'};
            %%
            
            % File name
            FN = char(strcat(app.SubjectTextArea.Value, '_', app.DateTextArea.Value, '_', num2str(app.SessionEditField.Value)));
            %%
            
            %JSON (path is par.savepath)
            json.version = '1.0';
            json.project = Par.dbfields.project;
            json.dataset = Par.dbfields.dataset;  
            json.date = Par.dbfields.date;
            json.subject = Par.dbfields.subject;
            json.investigator = Par.dbfields.investigator;
            json.setup = Par.dbfields.setup;
            json.condition = Par.dbfields.condition;   %not essential
            json.stimulus = Par.dbfields.stimulus; %name (str) of function
            json.logfile = [FN '_log']; %name for your logfile
            json.session = app.SessionEditField.Value;
            % json.script = thisRecord.script;
            json.display = Par.PTB;
            savejson('', json, [FN '_session.json']);
            %%
            
            % Save log (path is par.savepath)
            uisave({'data_table','perfdata', 'time', 'Par', 't_trl_abs', 't_lick_abs', 't_abs0', 'sound', }, [FN '_log']);

            %%
            
        end

        % Callback function
        function GetBLButtonPushed(app, event)
         global Par
            fprintf(Par.comm_Lick, 'IB'); % Get current BL level
        end

        % Callback function
        function SetButton_2Pushed(app, event)
         global Par
         fprintf(Par.comm_Lick, strcat('IH',num2str(app.ThInput.Value))); %Set threshold
        end

        % Callback function
        function SetButtonPushed(app, event)
          global Par
          fprintf(Par.comm_Lick, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time
        end

        % Callback function
        function SetButton_3Pushed(app, event)

            if Par.g2p == 1 && Par.lick == 1 && Par.logsave == 1
                Par.set = 1;
                app.ReadyLamp.Color = [0 1 0];
            elseif Par.g2p == 0 && Par.lick == 1 && Par.logsave == 1
                app.ReadyLamp.Color = [0.49 0.18 0.56];
                Par.set = 1;
                warndlg('Trial run without 2p!');
            else
                app.ReadyLamp.Color = [1 0 0];
                Par.set = 0;
                warndlg('NOT ready! Check lick sensor, S2P, or log path!');
                error('NOT ready! Check lick sensor, S2P, or log path!');
            end
        end

        % Button pushed function: StimButton
        function StimButtonPushed(app, event)
            global givestim
            givestim = 1;
        end

        % Button pushed function: ConnButton
        function ConnButtonPushed(app, event)
            startCS(app);
                           
        end

        % Button pushed function: DiscButton
        function DiscButtonPushed(app, event)
            discCS(app);
        end

        % Button pushed function: ApplyparametersButton
        function ApplyparametersButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CommunicationPanel = uipanel(app.UIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.comm_Lick_lamp = uilamp(app.CommunicationPanel);
            app.comm_Lick_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Lick_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Lick_connectButtonPushed, true);
            app.comm_Lick_connect.Text = 'Connect';
            app.LickEditFieldLabel = uilabel(app.CommunicationPanel);
            app.LickEditFieldLabel.Text = 'Lick';
            app.comm_Lick_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Lick_com.Value = 'com17';
            app.comm_2pPC_lamp = uilamp(app.CommunicationPanel);
            app.comm_2pPC_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_2pPC_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_2pPC_connectButtonPushed, true);
            app.comm_2pPC_connect.Text = 'Connect';
            app.pPCEditFieldLabel = uilabel(app.CommunicationPanel);
            app.pPCEditFieldLabel.Text = '2p PC';
            app.comm_2pPC_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_2pPC_com.Value = 'G2P';
            app.comm_Motion_lamp = uilamp(app.CommunicationPanel);
            app.comm_Motion_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Motion_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Motion_connectPushed, true);
            app.comm_Motion_connect.Enable = 'off';
            app.comm_Motion_connect.Text = 'Connect';
            app.MotionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.comm_Motion_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Motion_com.Value = 'com17';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.SubjectTextAreaLabel = uilabel(app.LogPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.LogPanel);
            app.SubjectTextArea.Editable = 'off';
            app.DateTextAreaLabel = uilabel(app.LogPanel);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.LogPanel);
            app.DateTextArea.Editable = 'off';
            app.SavepathTextAreaLabel = uilabel(app.LogPanel);
            app.SavepathTextAreaLabel.Text = 'Save path';
            app.SavepathTextArea = uitextarea(app.LogPanel);
            app.SavepathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.LogPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LogPanel, 'numeric');
            app.SessionEditField.Value = 1;
            app.ICMSparametersPanel = uipanel(app.UIFigure);
            app.ICMSparametersPanel.Title = 'ICMS parameters';
            app.PolarityDropDownLabel = uilabel(app.ICMSparametersPanel);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.ICMSparametersPanel);
            app.PolarityDropDown.Items = {'CF', 'AF'};
            app.PolarityDropDown.Value = 'CF';
            app.PhasewidthEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.PhasewidthEditFieldLabel.Text = 'Phase width';
            app.PhasewidthEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.PhasewidthEditField.Value = '170';
            app.AmplitudeEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.AmplitudeEditField.Value = '20';
            app.FrequencyEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.FrequencyEditField.Value = '300';
            app.HzLabel = uilabel(app.ICMSparametersPanel);
            app.HzLabel.Text = 'Hz';
            app.NrpulsesEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.NrpulsesEditFieldLabel.Text = 'Nr pulses';
            app.NrpulsesEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.NrpulsesEditField.Value = '1';
            app.sLabel = uilabel(app.ICMSparametersPanel);
            app.sLabel.Text = 'μs';
            app.ALabel = uilabel(app.ICMSparametersPanel);
            app.ALabel.Text = 'μA';
            app.sLabel_2 = uilabel(app.ICMSparametersPanel);
            app.sLabel_2.Text = 'μs';
            app.IPDEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.IPDEditFieldLabel.Text = 'IPD';
            app.IPDEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.IPDEditField.Value = '60';
            app.ElectrodesEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.ElectrodesEditField.Value = '10';
            app.ALRDropDownLabel = uilabel(app.ICMSparametersPanel);
            app.ALRDropDownLabel.Text = 'ALR?';
            app.ALRDropDown = uidropdown(app.ICMSparametersPanel);
            app.ALRDropDown.Items = {'N', 'Y'};
            app.ALRDropDown.Value = 'N';
            app.ALRelecEditFieldLabel = uilabel(app.ICMSparametersPanel);
            app.ALRelecEditFieldLabel.Text = 'ALR elec?';
            app.ALRelecEditField = uieditfield(app.ICMSparametersPanel, 'text');
            app.CereStimPanel = uipanel(app.UIFigure);
            app.CereStimPanel.Title = 'CereStim';
            app.ConnButton = uibutton(app.CereStimPanel, 'push');
            app.ConnButton.ButtonPushedFcn = createCallbackFcn(app, @ConnButtonPushed, true);
            app.ConnButton.Text = 'Conn.';
            app.DiscButton = uibutton(app.CereStimPanel, 'push');
            app.DiscButton.ButtonPushedFcn = createCallbackFcn(app, @DiscButtonPushed, true);
            app.DiscButton.Text = 'Disc.';
            app.ApplyparametersButton = uibutton(app.CereStimPanel, 'push');
            app.ApplyparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyparametersButtonPushed, true);
            app.ApplyparametersButton.Text = 'Apply parameters';
            app.ConnectionLampLabel = uilabel(app.CereStimPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.CereStimPanel);
            app.StimDropDownLabel = uilabel(app.CereStimPanel);
            app.StimDropDownLabel.Text = 'Stim?';
            app.StimDropDown = uidropdown(app.CereStimPanel);
            app.StimDropDown.Items = {'Y', 'N'};
            app.StimDropDown.Value = 'N';
            app.DeliveryPanel = uipanel(app.UIFigure);
            app.DeliveryPanel.Title = 'Delivery';
            app.ModeLabel = uilabel(app.DeliveryPanel);
            app.ModeLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.DeliveryPanel);
            app.ModeDropDown.Items = {'A', 'M'};
            app.ModeDropDown.Value = 'M';
            app.ExecutioncontrolPanel = uipanel(app.UIFigure);
            app.ExecutioncontrolPanel.Title = 'Execution control';
            app.StartButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.StimButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StimButton.ButtonPushedFcn = createCallbackFcn(app, @StimButtonPushed, true);
            app.StimButton.Text = 'Stim';
            app.NumberofstimsLabel = uilabel(app.ExecutioncontrolPanel);
            app.NumberofstimsLabel.Text = 'Number of stims:';
            app.Text_NumStim = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumStim.Editable = 'off';
            app.incshamLabel = uilabel(app.ExecutioncontrolPanel);
            app.incshamLabel.Text = 'inc. sham:';
            app.Text_NumSham = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumSham.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICMS_2P_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end