classdef ICMS_psy2_noStimCatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ICMSPanel                      matlab.ui.container.Panel
        PolarityDropDownLabel          matlab.ui.control.Label
        PolarityDropDown               matlab.ui.control.DropDown
        PhasewEditFieldLabel           matlab.ui.control.Label
        PhasewEditField                matlab.ui.control.EditField
        AmplitudetobetestedALabel      matlab.ui.control.Label
        AmplitudetobetestedAEditField  matlab.ui.control.EditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.EditField
        NrpulsesEditFieldLabel         matlab.ui.control.Label
        NrpulsesEditField              matlab.ui.control.EditField
        sLabel_5                       matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        sLabel_6                       matlab.ui.control.Label
        IPDEditFieldLabel              matlab.ui.control.Label
        IPDEditField                   matlab.ui.control.EditField
        ElectrodesEditFieldLabel       matlab.ui.control.Label
        ElectrodesEditField            matlab.ui.control.EditField
        ConnectButton                  matlab.ui.control.Button
        ConnectionLamp                 matlab.ui.control.Lamp
        StimDropDownLabel_2            matlab.ui.control.Label
        RepeatsEditFieldLabel          matlab.ui.control.Label
        RepeatsEditField               matlab.ui.control.NumericEditField
        SetButton_3                    matlab.ui.control.Button
        StimDropDownLabel_3            matlab.ui.control.Label
        CatchfracEditFieldLabel        matlab.ui.control.Label
        CatchfracEditField             matlab.ui.control.NumericEditField
        TrialstructurePanel            matlab.ui.container.Panel
        sLabel                         matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        sLabel_3                       matlab.ui.control.Label
        sLabel_4                       matlab.ui.control.Label
        RewardEditFieldLabel           matlab.ui.control.Label
        RewardEditField                matlab.ui.control.NumericEditField
        NolickEditFieldLabel           matlab.ui.control.Label
        NolickEditField                matlab.ui.control.NumericEditField
        ResponseEditFieldLabel         matlab.ui.control.Label
        ResponseEditField              matlab.ui.control.NumericEditField
        ITIEditFieldLabel              matlab.ui.control.Label
        ITI1                           matlab.ui.control.NumericEditField
        toLabel                        matlab.ui.control.Label
        ITI2                           matlab.ui.control.NumericEditField
        SetButton                      matlab.ui.control.Button
        FAtimeoutEditFieldLabel        matlab.ui.control.Label
        FAtimeoutEditField             matlab.ui.control.NumericEditField
        sLabel_7                       matlab.ui.control.Label
        CommunicationPanel             matlab.ui.container.Panel
        comm_Lick_lamp                 matlab.ui.control.Lamp
        comm_Lick_connect              matlab.ui.control.Button
        LickEditFieldLabel             matlab.ui.control.Label
        comm_Lick_com                  matlab.ui.control.EditField
        comm_2pPC_lamp                 matlab.ui.control.Lamp
        comm_2pPC_connect              matlab.ui.control.Button
        pPCEditFieldLabel              matlab.ui.control.Label
        comm_2pPC_com                  matlab.ui.control.EditField
        comm_Motion_lamp               matlab.ui.control.Lamp
        comm_Motion_connect            matlab.ui.control.Button
        MotionEditFieldLabel           matlab.ui.control.Label
        comm_Motion_com                matlab.ui.control.EditField
        LogPanel                       matlab.ui.container.Panel
        SubjectTextAreaLabel           matlab.ui.control.Label
        SubjectTextArea                matlab.ui.control.TextArea
        DateTextAreaLabel              matlab.ui.control.Label
        DateTextArea                   matlab.ui.control.TextArea
        SavepathTextAreaLabel          matlab.ui.control.Label
        SavepathTextArea               matlab.ui.control.TextArea
        BrowseButton                   matlab.ui.control.Button
        SessionEditFieldLabel          matlab.ui.control.Label
        SessionEditField               matlab.ui.control.NumericEditField
        ExecutioncontrolPanel          matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        CurrentBLEditField             matlab.ui.control.NumericEditField
        GetBLButton                    matlab.ui.control.Button
        SetButton_2                    matlab.ui.control.Button
        ThInput                        matlab.ui.control.NumericEditField
        NolickEditFieldLabel_2         matlab.ui.control.Label
        InterjectButton                matlab.ui.control.Button
        InterjectofpassivesEditFieldLabel  matlab.ui.control.Label
        InterjectofpassivesEditField   matlab.ui.control.NumericEditField
        LickLampLabel                  matlab.ui.control.Label
        LickLamp                       matlab.ui.control.Lamp
        MotionLampLabel                matlab.ui.control.Label
        MotionLamp                     matlab.ui.control.Lamp
    end

    
    methods (Access = public)
        
        function show_dot(app)
            global Par    
        
            dotXpos = rand * Par.PTB.screenXpixels; % SETUP SPECIFIC! Azm coverage, full screen, which is -62 to 62 deg in this setup!
            dotYpos = rand * 511 + 257; % SETUP SPECIFIC! Alt coverage 30 deg up and 25 deg down; 
            
            dotColorRGB = [255 255 255];
            dotColorRGBcontrastModulated = (128 + 128*app.LevelstobetestedEditField.Value)/256; 
            dotSizePx = app.DiaPxEditField.Value;
            Screen('FillRect', Par.PTB.window,[128/256 128/256 128/256], Par.PTB.windowRect);
            Screen('DrawDots', Par.PTB.window, [dotXpos dotYpos], dotSizePx, dotColorRGBcontrastModulated, [], 2);
            Screen('Flip', Par.PTB.window);
            
            Par.visual.priming = app.PrimingDropDown.Value;
            Par.visual.dotSizePx = app.DiaPxEditField.Value;
            Par.visual.contrast = app.LevelstobetestedEditField.Value; 
        
        end
        
        function show_gray(app)
            global Par
            Screen('FillRect', Par.PTB.window,[128/256 128/256 128/256], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
        end
      
         function show_black(app)
            global Par
            Screen('FillRect', Par.PTB.window,[0 0 0], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
         end
         
        function armwf(app,n)
        % arm the n-th waveform in CereStim
            global wf stimulator
            stimulator.beginSequence;
                stimulator.beginGroup;
                    for i = size(wf.elec,2)
                        stimulator.autoStim(wf.elec(1,i),n);
                    end
                stimulator.endGroup;
            stimulator.endSequence;
        end
        
      function armcatch(app,n)
       % arm n-th wf for catch stim at a unused channel
       % for Freiburg probe we take BR channel 30 as it is folating in air
           global wf stimulator
           stimulator.beginSequence;
            % stimulator.beginGroup;
               stimulator.autoStim(30,n);
            % stimulator.endGroup;
           stimulator.endSequence;
      end
         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Par
            openobj = instrfind;
            if ~isempty(openobj)
                fclose(openobj); % close all serial ports
            end
            Par.dbfields = getdbfields('VC');
            app.SubjectTextArea.Value = Par.dbfields.subject;
            app.DateTextArea.Value = Par.dbfields.date;
            Par.ICMS = 0;
            
            %Initialize DAS
            try
                dasint(0);
                Par.das = 1;
            catch ME
                Par.das = 0;
                disp('No DAS available')
            end
            
            % Initialize PTB
            PsychDefaultSetup(2);
            Par.PTB.screenNumber = 2;
            try 
                load('C:\MyGammaTable.mat',"gammaTable");
            catch ME
                waitfor(msgbox('Unable to performa gamma correction! Make sure MyGammaTable.mat is under C:\ and run the program again!'));
            end
            Screen('LoadNormalizedGammaTable', Par.PTB.screenNumber, gammaTable*[1 1 1]);
            [Par.PTB.window, Par.PTB.windowRect] = PsychImaging('OpenWindow', Par.PTB.screenNumber, 128/256);
            [Par.PTB.screenXpixels, Par.PTB.screenYpixels] = Screen('WindowSize', Par.PTB.window);
            [Par.PTB.xCenter, Par.PTB.yCenter] = RectCenter(Par.PTB.windowRect);
            Screen('BlendFunction', Par.PTB.window, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);          
          
            % Others
            Par.CsConn = 0;
         
        end

        % Button pushed function: comm_Lick_connect
        function comm_Lick_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_Lick = serial(app.comm_Lick_com.Value, 'Baudrate', 115200);
                fopen(Par.comm_Lick);
                app.comm_Lick_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check port number.');
            end 
        end

        % Button pushed function: comm_Motion_connect
        function comm_Motion_connectPushed(app, event)
            global Par
            try
                Par.comm_Motion = serial(app.comm_Motion_com.Value, 'Baudrate', 9600);
                fopen(Par.comm_Motion);
                app.comm_Motion_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check port number.');
            end
        end

        % Button pushed function: comm_2pPC_connect
        function comm_2pPC_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_2p = udp(app.comm_2pPC_com.Value, 'RemotePort', 7000);
                app.comm_2pPC_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check IP.')
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SavepathTextArea.Value = uigetdir;
            global Par
            Par.savepath = app.SavepathTextArea.Value;
            cd(char(Par.savepath));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global Par
            global passive
            global data
            global lpt
            global time
            global trl
            global stimulator
            global t_trl_abs t_lick_abs
            global wf
            
            Par.run = 1;
            time = [];
            
           
            % ================ Housekeeping =====================
            
            % Check if savepath exists
            if ~isfield(Par,'savepath')
                warndlg('Savepath is not defined!')
                app.BrowseButtonPushed;
            end
            %%
            
            licksen = Par.comm_Lick;
            motionsen = Par.comm_Motion;
            % Empty sensor buffer
            while licksen.BytesAvailable
                StrOut = fscanf(licksen);
            end
            while motionsen.BytesAvailable
                StrOut = fscanf(motionsen);
            end
            
            % Make a matrix of amplitudes/waveform numbers
            wfnum = [1:1:wf.numAmps]';
            wfnum = repmat(wfnum,app.RepeatsEditField.Value,1);
            % calculate num of ct
            ctnum = ceil(app.CatchfracEditField.Value * (size(wfnum,1)/(1-app.CatchfracEditField.Value)));
            
            wfnum_plusCatch = [wfnum; zeros(ctnum,1)];
            wfnum_plusCatch = wfnum_plusCatch(randperm(size(wfnum_plusCatch,1))); % randomize
            wfnum_plusCatch = wfnum_plusCatch(randperm(size(wfnum_plusCatch,1))); % randomize again
            
            
            trl_max = size(wfnum_plusCatch,1); % Maximum number of trl per run; used for preallocating log (var: data)
            data = zeros(trl_max,6);
            t_lick = 0;
            step = 0; 
            fprintf(licksen, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time
            fprintf(licksen, strcat('IA',num2str(app.ResponseEditField.Value*1000))); % Set response window duration
            passive = 0;
            t0 = tic;
            trl = 0;
            
            t_abs0 = tic;
            t_lick_abs = []; % to store all the lick time relavtive to t_abs0;
            t_trl_abs = []; % to store all trial type (row 1) AND trial time (row 2) relative to t_abs0;
            
            %%

            % ================ Game =====================
                while Par.run == 1
                    m1 = tic; % marker m1; counter for one while loop. 
                    
                    % Make sure the lick lamp, if turned on, stays on for
                    % at least 100 ms.
                    try
                        if toc(m_licklamp) > 0.1
                            app.LickLamp.Color = [1 0 0]; % Reset the lick lamp to OFF color. 
                        end
                    catch ME
                    end
                    %%
                        
                        
                    % Block: Arduino I/O
                    while licksen.BytesAvailable
                        StrOut = fscanf(licksen);
                        if strncmp('L', StrOut, 1)
                            sNr_lick = StrOut(2);
                            t_lick = toc(t0);
                            t_lick_abs(end+1) = toc(t_abs0);
                            app.LickLamp.Color = [0 1 0];
                            m_licklamp = tic;
                        elseif strncmp('Reward', StrOut, 6) 
                            data(trl,4)= 1;
                            data(trl,5)= str2num(fscanf(licksen));
                            trash = fscanf(licksen);
                        elseif strncmp('Error', StrOut, 5)
                            data(trl,4)= 1;
                            data(trl,5)= str2num(fscanf(licksen));
                            FA_punish = 1;
                            disp('----FA!')
%                             trash = fscanf(licksen);
                        elseif strncmp('Base1', StrOut, 5)
                            app.CurrentBLEditField.Value = str2double(StrOut(7:end));
                        end
                    end
                    
                    % Block: Check ICMS status
                        if Par.CsConn == 1 % && app.StimDropDown.Value == 'Y'
                            Par.ICMS = 1;
                        else
                            Par.ICMS = 0;
                        end

                    
                    % Block: Normal session BUT passive interjection at max
                    % current amp on the list
                    if step == 0 && passive == 1
                        FA_punish = 0;
                        step = 100;
                        n = 0;
                    end
                        
                    if step == 100
                        
                        if Par.ICMS == 1
                            armwf(app, max(wfnum_plusCatch));
                            stimulator.play(1);
                        end
                       
                        disp('Trial: Passive')
                        iti = datasample([app.ITI1.Value:0.25:app.ITI2.Value],1);
                        n = n + 1;
                        step = 101;
                        t100 = tic;
                    end
                    
                    if step == 101 && toc(t100) > 0.1 %this step is needed to stop the visual after, say 0.1 s
                        fprintf(licksen, 'IP'); % water delivery
                        step = 102;
                    end
                                      
                    if step == 102 && toc(t100) > iti && n < app.InterjectofpassivesEditField.Value
                        step = 100;
                    elseif step == 102 && toc(t100) > iti && n == app.InterjectofpassivesEditField.Value
                        step = 0;
                        passive = 0;
                        t0 = tic;
                    end
                     
                    % Block: No lick period
                    if step == 0 && passive == 0
                        FA_punish = 0;
                        jitter = datasample([-2:0.5:2], 1);
                        if t_lick == 0 && toc(t0) > (app.NolickEditField.Value + jitter)
                            step = 1;
                        elseif t_lick > 0 && (toc(t0) - t_lick > (app.NolickEditField.Value) + jitter)
                            step = 1;
                        end
                    end
                    %%
                    
                    % Block: Stimulus delivery
                    if step == 1 
                        
                        trl = trl + 1;
                        
                        if wfnum_plusCatch(trl) ~= 0
                            % real trial
                            armwf(app,wfnum_plusCatch(trl));
                            t_trl_abs(1,end+1) = wf.Amps(wfnum_plusCatch(trl)); % Specify test amplitude in trial time record
                            t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                            
                            data(trl,:) = [now 1 0 0 0 wf.Amps(wfnum_plusCatch(trl))]; % Outcome defaults to Miss (0); If hit, then data(trl,4) will be set to 1.
                            
                            stimulator.play(1);
                            
                            t_sti = tic;
                            fprintf(licksen, 'IE1');
                            fprintf(licksen, 'IS');
                            disp(wf.Amps(wfnum_plusCatch(trl)));
                            step = 2;
                        else
                            % catch trial
                            % armcatch(app,max(wfnum_plusCatch)); % arm an unused channel (defined in fcn) at the highest amp for catch stim; 

                            t_trl_abs(1,end+1) = 0; % Specify test amplitude in trial time record
                            t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time

                            data(trl,:) = [now 1 0 0 0 0];  % Default to miss; if there's an error (FA) then this, data(trl,4), will be set to 1
                            
                            % stimulator.play(1);
                            fprintf(licksen, 'IS');
                            
                            t_sti = tic;
                            disp(0);
                            step = 2;  
                        end

                        iti = datasample([app.ITI1.Value:0.25:app.ITI2.Value],1);
                        
                    end
                    
                    if step == 2 && toc(t_sti) > (app.ResponseEditField.Value)
                        if FA_punish == 1
                            iti = iti + app.FAtimeoutEditField.Value;
                            step = 3;
                        else
                            step = 3;
                        end
                    end

                                        
                    if step == 3 && toc(t_sti) > (app.ResponseEditField.Value + app.RewardEditField.Value + iti)
                        if trl == size(wfnum_plusCatch,1)
                            f = warndlg('All stimuli delivered');
                            StopButtonPushed(app);
                        else
                           step = 0;
                           t_lick = 0;
                           t0 = tic;
                        end 
                    end
                    
                    drawnow limitrate; %this must run every loop otherwise it lags!
                    xt8 = toc(m1);
                    time = [time; xt8];

                end


        end

        % Button pushed function: InterjectButton
        function InterjectButtonPushed(app, event)
            global passive
            passive = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global Par
            global data
            global perfdata
            global time
            global trl
            global fig1
            global wf
            global stimulator
            global t_trl_abs t_lick_abs
            
            % Stop trial run
            Par.run = 0;
            %%
            
            % Disconnect CereStim
            if Par.CsConn
                 stimulator.disconnect;
                 app.ConnectionLamp.Color = [1 0 0];
                 disp('Stimulator disconnected.')
            end
            
            %%
            
            % Prepare table for log file
            data_table = array2table(data(1:trl,:));
            data_table.Properties.VariableNames = {'DateTime', 'TrlType', 'Visual', 'Outcome', 'RT', 'Amp'};
            %%
            
            % File name
            FN = char(strcat(app.SubjectTextArea.Value, '_', app.DateTextArea.Value, '_Psy_', num2str(app.SessionEditField.Value)));
            %%
            
            %JSON (path is par.savepath)
            json.version = '1.0';
            json.project = Par.dbfields.project;
            json.dataset = Par.dbfields.dataset;  
            json.date = Par.dbfields.date;
            json.subject = Par.dbfields.subject;
            json.investigator = Par.dbfields.investigator;
            json.setup = Par.dbfields.setup;
            json.condition = Par.dbfields.condition;   %not essential
            json.stimulus = Par.dbfields.stimulus; %name (str) of function
            json.logfile = [FN '_log']; %name for your logfile
            json.session = app.SessionEditField.Value;
            % json.script = thisRecord.script;
            json.display = Par.PTB;
            savejson('', json, [FN '_session.json']);
            %%
            
            % Save log (path is par.savepath)
                uisave({'data_table','perfdata', 'time', 'Par', 'wf', 't_trl_abs', 't_lick_abs'}, [FN '_log']);
            %%
            
        end

        % Button pushed function: GetBLButton
        function GetBLButtonPushed(app, event)
        global Par
            fprintf(Par.comm_Lick, 'IB'); % Get current BL level
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
        global Par
            fprintf(Par.comm_Lick, strcat('IH',num2str(app.ThInput.Value))); %Set threshold
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global wf
            global stimulator
            global Par
            

            % Stimulator connection
            stimulator = cerestim96();
            my_devices = stimulator.scanForDevices;
            pause(0.5);
            if isempty(my_devices)
                f = warndlg('No Cerestim found!');
                
            else
                stimulator.selectDevice(0); % 0 is the first Cerestim found by scanForDevices
                pause(0.5);
                stimulator.connect;
                pause(0.5);
                Par.CsConn = 1; 
                app.ConnectionLamp.Color = [0 1 0];
            end


%                 if Par.das == 0
%                     f = warndlg('No DAS card! Unable to do ICMS!');
%                     
%                 else
%                     Par.ICMS = 1; % Now it is ready to be activated by DAS bit. 
%                     app.ConnectionLamp.Color = [0 1 0];
%                 end
                           
        end

        % Callback function
        function DiscButtonPushed(app, event)
%             global stimulator
%             
%             x = stimulator.isConnected();
%             if ~x
%                 disp('There is nothing to disconnect!')
%             else
%                 stimulator.disconnect;
%                 app.ConnectionLamp.Color = [1 0 0];
%                 disp('Stimulator disconnected.')
%             end
%             clear x;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
           global Par
           fprintf(Par.comm_Lick, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time

        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            global wf
            global stimulator
            global Par
            
            wf.Amps = unique(str2num(app.AmplitudetobetestedAEditField.Value)); % here "str2double" will ignore comma in list!
            wf.numAmps = size(wf.Amps,2); % determines num of waveforms
            
            if wf.numAmps > 15
                f = warndlg('More than maximum number of amplitudes (15) entered!');
            else
                % wf.id = 1;
                
                if app.PolarityDropDown.Value == 'CF'
                    wf.polarity = 1; %CF=1; AF=0;
                else
                    wf.polarity = 0;
                end
                wf.nPulses = str2double(app.NrpulsesEditField.Value);
                wf.elec = str2num(app.ElectrodesEditField.Value);
                wf.width = str2double(app.PhasewEditField.Value);
                wf.ipd = str2double(app.IPDEditField.Value);
                wf.freq = str2double(app.FrequencyEditField.Value);
                
                for i = 1:wf.numAmps
                    wf.id = i;
                    stimulator.setStimPattern('waveform',wf.id,...
                        'polarity',wf.polarity,...
                        'pulses',wf.nPulses,...
                        'amp1',wf.Amps(i),...
                        'amp2',wf.Amps(i),...
                        'width1',wf.width,...
                        'width2',wf.width,...
                        'interphase',wf.ipd,...
                        'frequency',wf.freq); 
                end

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ICMSPanel = uipanel(app.UIFigure);
            app.ICMSPanel.Title = 'ICMS';
            app.PolarityDropDownLabel = uilabel(app.ICMSPanel);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.ICMSPanel);
            app.PolarityDropDown.Items = {'CF', 'AF'};
            app.PolarityDropDown.Value = 'CF';
            app.PhasewEditFieldLabel = uilabel(app.ICMSPanel);
            app.PhasewEditFieldLabel.Text = 'Phase w.';
            app.PhasewEditField = uieditfield(app.ICMSPanel, 'text');
            app.PhasewEditField.Value = '170';
            app.AmplitudetobetestedALabel = uilabel(app.ICMSPanel);
            app.AmplitudetobetestedALabel.Text = 'Amplitude to be tested (μA):';
            app.AmplitudetobetestedAEditField = uieditfield(app.ICMSPanel, 'text');
            app.AmplitudetobetestedAEditField.Value = '1,5,9,13,17,21,25';
            app.FrequencyEditFieldLabel = uilabel(app.ICMSPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ICMSPanel, 'text');
            app.FrequencyEditField.Value = '300';
            app.NrpulsesEditFieldLabel = uilabel(app.ICMSPanel);
            app.NrpulsesEditFieldLabel.Text = 'Nr pulses';
            app.NrpulsesEditField = uieditfield(app.ICMSPanel, 'text');
            app.NrpulsesEditField.Value = '30';
            app.sLabel_5 = uilabel(app.ICMSPanel);
            app.sLabel_5.Text = 'μs';
            app.HzLabel = uilabel(app.ICMSPanel);
            app.HzLabel.Text = 'Hz';
            app.sLabel_6 = uilabel(app.ICMSPanel);
            app.sLabel_6.Text = 'μs';
            app.IPDEditFieldLabel = uilabel(app.ICMSPanel);
            app.IPDEditFieldLabel.Text = 'IPD';
            app.IPDEditField = uieditfield(app.ICMSPanel, 'text');
            app.IPDEditField.Value = '60';
            app.ElectrodesEditFieldLabel = uilabel(app.ICMSPanel);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.ICMSPanel, 'text');
            app.ElectrodesEditField.Value = '13';
            app.ConnectButton = uibutton(app.ICMSPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLamp = uilamp(app.ICMSPanel);
            app.StimDropDownLabel_2 = uilabel(app.ICMSPanel);
            app.StimDropDownLabel_2.Text = 'CereStim';
            app.RepeatsEditFieldLabel = uilabel(app.ICMSPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.RepeatsEditField = uieditfield(app.ICMSPanel, 'numeric');
            app.RepeatsEditField.Value = 10;
            app.SetButton_3 = uibutton(app.ICMSPanel, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.StimDropDownLabel_3 = uilabel(app.ICMSPanel);
            app.StimDropDownLabel_3.Text = 'Par. set';
            app.CatchfracEditFieldLabel = uilabel(app.ICMSPanel);
            app.CatchfracEditFieldLabel.Text = 'Catch frac.';
            app.CatchfracEditField = uieditfield(app.ICMSPanel, 'numeric');
            app.CatchfracEditField.Value = 0.3;
            app.TrialstructurePanel = uipanel(app.UIFigure);
            app.TrialstructurePanel.Title = 'Trial structure';
            app.sLabel = uilabel(app.TrialstructurePanel);
            app.sLabel.Text = '±2 s ';
            app.sLabel_2 = uilabel(app.TrialstructurePanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.TrialstructurePanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.TrialstructurePanel);
            app.sLabel_4.Text = 's';
            app.RewardEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.RewardEditFieldLabel.Text = 'Reward';
            app.RewardEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.RewardEditField.Value = 0.1;
            app.NolickEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.NolickEditFieldLabel.Text = 'No-lick';
            app.NolickEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.NolickEditField.Value = 3;
            app.ResponseEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.ResponseEditFieldLabel.Text = 'Response';
            app.ResponseEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ResponseEditField.Value = 1;
            app.ITIEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITI1 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI1.Value = 3;
            app.toLabel = uilabel(app.TrialstructurePanel);
            app.toLabel.Text = 'to';
            app.ITI2 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI2.Value = 5;
            app.SetButton = uibutton(app.TrialstructurePanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.FAtimeoutEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.FAtimeoutEditFieldLabel.Text = 'FA timeout';
            app.FAtimeoutEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.FAtimeoutEditField.Value = 3;
            app.sLabel_7 = uilabel(app.TrialstructurePanel);
            app.sLabel_7.Text = 's';
            app.CommunicationPanel = uipanel(app.UIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.comm_Lick_lamp = uilamp(app.CommunicationPanel);
            app.comm_Lick_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Lick_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Lick_connectButtonPushed, true);
            app.comm_Lick_connect.Text = 'Connect';
            app.LickEditFieldLabel = uilabel(app.CommunicationPanel);
            app.LickEditFieldLabel.Text = 'Lick';
            app.comm_Lick_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Lick_com.Value = 'com17';
            app.comm_2pPC_lamp = uilamp(app.CommunicationPanel);
            app.comm_2pPC_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_2pPC_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_2pPC_connectButtonPushed, true);
            app.comm_2pPC_connect.Text = 'Connect';
            app.pPCEditFieldLabel = uilabel(app.CommunicationPanel);
            app.pPCEditFieldLabel.Text = '2p PC';
            app.comm_2pPC_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_2pPC_com.Value = '192.87.10.153';
            app.comm_Motion_lamp = uilamp(app.CommunicationPanel);
            app.comm_Motion_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Motion_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Motion_connectPushed, true);
            app.comm_Motion_connect.Text = 'Connect';
            app.MotionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.comm_Motion_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Motion_com.Value = 'com17';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.SubjectTextAreaLabel = uilabel(app.LogPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.LogPanel);
            app.SubjectTextArea.Editable = 'off';
            app.DateTextAreaLabel = uilabel(app.LogPanel);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.LogPanel);
            app.DateTextArea.Editable = 'off';
            app.SavepathTextAreaLabel = uilabel(app.LogPanel);
            app.SavepathTextAreaLabel.Text = 'Save path';
            app.SavepathTextArea = uitextarea(app.LogPanel);
            app.SavepathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.LogPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LogPanel, 'numeric');
            app.SessionEditField.Value = 1;
            app.ExecutioncontrolPanel = uipanel(app.UIFigure);
            app.ExecutioncontrolPanel.Title = 'Execution control';
            app.StartButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CurrentBLEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.CurrentBLEditField.Editable = 'off';
            app.GetBLButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.GetBLButton.ButtonPushedFcn = createCallbackFcn(app, @GetBLButtonPushed, true);
            app.GetBLButton.Text = 'Get BL';
            app.SetButton_2 = uibutton(app.ExecutioncontrolPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.ThInput = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.ThInput.Value = 1500;
            app.NolickEditFieldLabel_2 = uilabel(app.ExecutioncontrolPanel);
            app.NolickEditFieldLabel_2.Text = 'thres. Δ';
            app.InterjectButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.InterjectButton.ButtonPushedFcn = createCallbackFcn(app, @InterjectButtonPushed, true);
            app.InterjectButton.Text = 'Interject';
            app.InterjectofpassivesEditFieldLabel = uilabel(app.ExecutioncontrolPanel);
            app.InterjectofpassivesEditFieldLabel.Text = 'Interject # of passives';
            app.InterjectofpassivesEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.InterjectofpassivesEditField.Value = 1;
            app.LickLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.LickLampLabel.Text = 'Lick';
            app.LickLamp = uilamp(app.ExecutioncontrolPanel);
            app.MotionLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.MotionLampLabel.Text = 'Motion';
            app.MotionLamp = uilamp(app.ExecutioncontrolPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICMS_psy2_noStimCatch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end