classdef app14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        ICMSPanel                     matlab.ui.container.Panel
        PolarityDropDownLabel         matlab.ui.control.Label
        PolarityDropDown              matlab.ui.control.DropDown
        PhasewidthEditFieldLabel      matlab.ui.control.Label
        PhasewidthEditField           matlab.ui.control.EditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.EditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.EditField
        NrpulsesEditFieldLabel        matlab.ui.control.Label
        NrpulsesEditField             matlab.ui.control.EditField
        sLabel_5                      matlab.ui.control.Label
        ALabel                        matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        sLabel_6                      matlab.ui.control.Label
        IPDEditFieldLabel             matlab.ui.control.Label
        IPDEditField                  matlab.ui.control.EditField
        ElectrodesEditFieldLabel      matlab.ui.control.Label
        ElectrodesEditField           matlab.ui.control.EditField
        ConnButton                    matlab.ui.control.Button
        DiscButton                    matlab.ui.control.Button
        ConnectionLampLabel           matlab.ui.control.Label
        ConnectionLamp                matlab.ui.control.Lamp
        StimDropDownLabel             matlab.ui.control.Label
        StimDropDown                  matlab.ui.control.DropDown
        UpdatestimparButton           matlab.ui.control.Button
        TrialstructurePanel           matlab.ui.container.Panel
        sLabel                        matlab.ui.control.Label
        sLabel_2                      matlab.ui.control.Label
        sLabel_3                      matlab.ui.control.Label
        sLabel_4                      matlab.ui.control.Label
        PassivesessionDropDownLabel   matlab.ui.control.Label
        PassivesessionDropDown        matlab.ui.control.DropDown
        RewardEditFieldLabel          matlab.ui.control.Label
        RewardEditField               matlab.ui.control.NumericEditField
        NolickEditFieldLabel          matlab.ui.control.Label
        NolickEditField               matlab.ui.control.NumericEditField
        ResponseEditFieldLabel        matlab.ui.control.Label
        ResponseEditField             matlab.ui.control.NumericEditField
        ITIEditFieldLabel             matlab.ui.control.Label
        ITI1                          matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        ITI2                          matlab.ui.control.NumericEditField
        SetButton                     matlab.ui.control.Button
        FAtimeoutEditFieldLabel       matlab.ui.control.Label
        FAtimeoutEditField            matlab.ui.control.NumericEditField
        sLabel_7                      matlab.ui.control.Label
        CommunicationPanel            matlab.ui.container.Panel
        comm_Lick_lamp                matlab.ui.control.Lamp
        comm_Lick_connect             matlab.ui.control.Button
        LickEditFieldLabel            matlab.ui.control.Label
        comm_Lick_com                 matlab.ui.control.EditField
        comm_2pPC_lamp                matlab.ui.control.Lamp
        comm_2pPC_connect             matlab.ui.control.Button
        pPCEditFieldLabel             matlab.ui.control.Label
        comm_2pPC_com                 matlab.ui.control.EditField
        comm_Motion_lamp              matlab.ui.control.Lamp
        comm_Motion_connect           matlab.ui.control.Button
        MotionEditFieldLabel          matlab.ui.control.Label
        comm_Motion_com               matlab.ui.control.EditField
        LogPanel                      matlab.ui.container.Panel
        SubjectTextAreaLabel          matlab.ui.control.Label
        SubjectTextArea               matlab.ui.control.TextArea
        DateTextAreaLabel             matlab.ui.control.Label
        DateTextArea                  matlab.ui.control.TextArea
        SavepathTextAreaLabel         matlab.ui.control.Label
        SavepathTextArea              matlab.ui.control.TextArea
        BrowseButton                  matlab.ui.control.Button
        SessionEditFieldLabel         matlab.ui.control.Label
        SessionEditField              matlab.ui.control.NumericEditField
        VisualPanel                   matlab.ui.container.Panel
        PrimingDropDownLabel          matlab.ui.control.Label
        PrimingDropDown               matlab.ui.control.DropDown
        IfYesthenLabel                matlab.ui.control.Label
        ContrastEditFieldLabel        matlab.ui.control.Label
        ContrastEditField             matlab.ui.control.NumericEditField
        DiaPxEditFieldLabel           matlab.ui.control.Label
        DiaPxEditField                matlab.ui.control.NumericEditField
        ExecutioncontrolPanel         matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        NumberoftrialsLabel           matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        Text_NumTrl                   matlab.ui.control.TextArea
        CurrentBLEditField            matlab.ui.control.NumericEditField
        GetBLButton                   matlab.ui.control.Button
        SetButton_2                   matlab.ui.control.Button
        ThInput                       matlab.ui.control.NumericEditField
        NolickEditFieldLabel_2        matlab.ui.control.Label
        Trialcounterupdatedelay1trialLabel  matlab.ui.control.Label
        InterjectButton               matlab.ui.control.Button
        InterjectofpassivesEditFieldLabel  matlab.ui.control.Label
        InterjectofpassivesEditField  matlab.ui.control.NumericEditField
        LickLampLabel                 matlab.ui.control.Label
        LickLamp                      matlab.ui.control.Lamp
        MotionLampLabel               matlab.ui.control.Label
        MotionLamp                    matlab.ui.control.Lamp
        incpassivesLabel              matlab.ui.control.Label
        Text_NumPas                   matlab.ui.control.TextArea
        Performanceinstantaneouspast20trialsPanel  matlab.ui.container.Panel
        HLabel_2                      matlab.ui.control.Label
        Text_NumHit_i                 matlab.ui.control.TextArea
        CRLabel_2                     matlab.ui.control.Label
        Text_NumCR_i                  matlab.ui.control.TextArea
        MLabel_2                      matlab.ui.control.Label
        Text_NumMiss_i                matlab.ui.control.TextArea
        FALabel_2                     matlab.ui.control.Label
        Text_NumFA_i                  matlab.ui.control.TextArea
        HRLabel                       matlab.ui.control.Label
        Text_NumHR_i                  matlab.ui.control.TextArea
        FARLabel                      matlab.ui.control.Label
        Text_NumFAR_i                 matlab.ui.control.TextArea
        idLabel                       matlab.ui.control.Label
        Text_NumDprime_i              matlab.ui.control.TextArea
        PerformancecumulativePanel    matlab.ui.container.Panel
        HLabel_3                      matlab.ui.control.Label
        Text_NumHit_c                 matlab.ui.control.TextArea
        cdLabel                       matlab.ui.control.Label
        Text_NumDprime_c              matlab.ui.control.TextArea
        HRLabel_2                     matlab.ui.control.Label
        Text_NumHR_c                  matlab.ui.control.TextArea
        FARLabel_2                    matlab.ui.control.Label
        Text_NumFAR_c                 matlab.ui.control.TextArea
        CRLabel_3                     matlab.ui.control.Label
        Text_NumCR_c                  matlab.ui.control.TextArea
        MLabel_3                      matlab.ui.control.Label
        Text_NumMiss_c                matlab.ui.control.TextArea
        FALabel_3                     matlab.ui.control.Label
        Text_NumFA_c                  matlab.ui.control.TextArea
        PasswTSDropDownLabel          matlab.ui.control.Label
        PasswTSDropDown               matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        
        function show_dot(app)
            global Par    
        
            dotXpos = rand * Par.PTB.screenXpixels; % SETUP SPECIFIC! Azm coverage, full screen, which is -62 to 62 deg in this setup!
            dotYpos = rand * 511 + 257; % SETUP SPECIFIC! Alt coverage 30 deg up and 25 deg down; 
            
            dotColorRGB = [255 255 255];
            dotColorRGBcontrastModulated = (128 + 128*app.ContrastEditField.Value)/256; 
            dotSizePx = app.DiaPxEditField.Value;
            Screen('FillRect', Par.PTB.window,[128/256 128/256 128/256], Par.PTB.windowRect);
            Screen('DrawDots', Par.PTB.window, [dotXpos dotYpos], dotSizePx, dotColorRGBcontrastModulated, [], 2);
            Screen('Flip', Par.PTB.window);
            
            Par.visual.priming = app.PrimingDropDown.Value;
            Par.visual.dotSizePx = app.DiaPxEditField.Value;
            Par.visual.contrast = app.ContrastEditField.Value; 
        
        end
        
        function show_CK(app)
            global Par    
        
            dotXpos = rand * Par.PTB.screenXpixels; % SETUP SPECIFIC! Azm coverage, full screen, which is -62 to 62 deg in this setup!
            dotYpos = rand * 511 + 257; % SETUP SPECIFIC! Alt coverage 30 deg up and 25 deg down; 
            
            dotSizePx = app.DiaPxEditField.Value;
            
            % Define a simple 4 by 4 checker board
            checkerboard = repmat(eye(2), 2, 2);
            % Make the checkerboard into a texure (4 x 4 pixels)
            checkerTexture = Screen('MakeTexture', window, checkerboard);
            
            % We will scale our texure up to dotSizePX
            [s1, s2] = size(checkerboard);
            dstRect = [0 0 s1 s2] .* ceil(dotSizePx/s1);
            dstRect = CenterRectOnPointd(dstRect, dotXpos, dotYpos);
            
            % Draw the checkerboard texture to the screen. 
            filterMode = 0;
            Screen('DrawTextures', Par.PTB.window, checkerTexture, [],...
                dstRect, 45, filterMode);
            Screen('Flip', Par.PTB.window);
            
        end
        
        function show_gray(app)
            global Par
            Screen('FillRect', Par.PTB.window,[128/256 128/256 128/256], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
        end
      
         function show_black(app)
            global Par
            Screen('FillRect', Par.PTB.window,[0 0 0], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
        end
         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Par
            openobj = instrfind;
            if ~isempty(openobj)
                fclose(openobj); % close all serial ports
            end
            Par.dbfields = getdbfields('VC');
            app.SubjectTextArea.Value = Par.dbfields.subject;
            app.DateTextArea.Value = Par.dbfields.date;
            Par.ICMS = 0;
            
            %Initialize DAS
            try
                dasint(0);
                Par.das = 1;
            catch ME
                Par.das = 0;
                disp('No DAS available')
            end
            
            % Initialize PTB
            PsychDefaultSetup(2);
            Par.PTB.screenNumber = 2;
            try 
                load('C:\MyGammaTable.mat',"gammaTable");
                Screen('LoadNormalizedGammaTable', Par.PTB.screenNumber, gammaTable*[1 1 1]);
            catch ME
                waitfor(msgbox('Unable to performa gamma correction! Make sure MyGammaTable.mat is under C:\ and run the program again!'));
            end

%             Screen('Preference', 'SkipSyncTests', 1);
            [Par.PTB.window, Par.PTB.windowRect] = PsychImaging('OpenWindow', Par.PTB.screenNumber, 128/256);
            [Par.PTB.screenXpixels, Par.PTB.screenYpixels] = Screen('WindowSize', Par.PTB.window);
            [Par.PTB.xCenter, Par.PTB.yCenter] = RectCenter(Par.PTB.windowRect);
            Screen('BlendFunction', Par.PTB.window, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            
            % Prepare the figure axes
            global fig1
            fig1 = animatedline(app.UIAxes);
           
            % Others
            Par.CsConn = 0;
         
        end

        % Button pushed function: comm_Lick_connect
        function comm_Lick_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_Lick = serial(app.comm_Lick_com.Value, 'Baudrate', 115200);
                fopen(Par.comm_Lick);
                app.comm_Lick_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check port number.');
            end 
        end

        % Button pushed function: comm_Motion_connect
        function comm_Motion_connectPushed(app, event)
            global Par
            try
                Par.comm_Motion = serial(app.comm_Motion_com.Value, 'Baudrate', 9600);
                fopen(Par.comm_Motion);
                app.comm_Motion_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check port number.');
            end
        end

        % Button pushed function: comm_2pPC_connect
        function comm_2pPC_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_2p = udp(app.comm_2pPC_com.Value, 'RemotePort', 7000);
                app.comm_2pPC_lamp.Color = [0 1 0];
            catch ME
                disp('Error! Check IP.')
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SavepathTextArea.Value = uigetdir;
            global Par
            Par.savepath = app.SavepathTextArea.Value;
            cd(char(Par.savepath));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global Par
            global passive
            global data
            global perfdata
            global fig1
            global lpt
            global time
            global trl
            global stimulator
            global t_trl_abs t_lick_abs
            
            Par.run = 1;
            time = [];
            
           
            % ================ Housekeeping =====================
            
            % Check if savepath exists
            if ~isfield(Par,'savepath')
                warndlg('Savepath is not defined!')
                app.BrowseButtonPushed;
            end
            %%
            
            licksen = Par.comm_Lick;
            motionsen = Par.comm_Motion;
            % Empty sensor buffer
            while licksen.BytesAvailable
                StrOut = fscanf(licksen);
            end
            while motionsen.BytesAvailable
                StrOut = fscanf(motionsen);
            end
            
            trl_max = 400; % Maximum number of trl per run; used for preallocating log (var: data)
            data = zeros(trl_max,6);
            t_lick = 0;
            step = 0; 
            fprintf(licksen, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time
            fprintf(licksen, strcat('IA',num2str(app.ResponseEditField.Value*1000))); % Set response window duration
            passive = 0;
            t0 = tic;
            trl = 0;
            perfdata.num_trl = 0;
            perfdata.num_sti_c = 0;
            perfdata.num_nosti_c = 0;
            perfdata.num_sti_nosti_c = 0;
            perfdata.log = zeros(trl_max,16);
            
            t_abs0 = tic;
            t_lick_abs = []; % to store all the lick time relavtive to t_abs0;
            t_trl_abs = []; % to store all trial type (row 1) AND trial time (row 2) relative to t_abs0;
            
            % Initialize the mini-block of 4
            id_m = [1 1 0 0];
            id_m = id_m(randperm(length(id_m)));
            id_i = 0;
            %%
            
            clf(fig1); % clear display figure before new run
            
            % ================ Game =====================
                while Par.run == 1
                    m1 = tic; % marker m1; counter for one while loop. 
                    
                    % Make sure the lick lamp, if turned on, stays on for
                    % at least 100 ms.
                    try
                        if toc(m_licklamp) > 0.1
                            app.LickLamp.Color = [1 0 0]; % Reset the lick lamp to OFF color. 
                        end
                    catch ME
                    end
                    %%
                        
                        
                    % Block: Arduino I/O
                    while licksen.BytesAvailable
                        StrOut = fscanf(licksen);
                        if strncmp('L', StrOut, 1)
                            sNr_lick = StrOut(2);
                            t_lick = toc(t0);
                            t_lick_abs(end+1) = toc(t_abs0);
                            app.LickLamp.Color = [0 1 0];
                            m_licklamp = tic;
                        elseif strncmp('Reward', StrOut, 6) 
                            data(trl,4)= 1;
                            data(trl,5)= str2num(fscanf(licksen));
                            trash = fscanf(licksen);
                        elseif strncmp('Error', StrOut, 5)
                            data(trl,4)= 0;
                            data(trl,5)= str2num(fscanf(licksen));
%                             trash = fscanf(licksen);
                        elseif strncmp('Base1', StrOut, 5)
                            app.CurrentBLEditField.Value = str2double(StrOut(7:end));
                        end
                    end
                    
                    % Block: Check ICMS status
                        if Par.CsConn == 1 && app.StimDropDown.Value == 'Y'
                            Par.ICMS = 1;
                        else
                            Par.ICMS = 0;
                        end

   
                    % Block: Passive session 
                    if app.PassivesessionDropDown.Value == 'Y' && step == 0
                        trl = trl + 1;
                        
                        t_trl_abs(1,end+1) = 100; % Specify trl ID in trial time record
                        t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                        
                        if app.PrimingDropDown.Value == 'Y'
                            show_dot(app);
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Deliver water at the end of passive
                            tm1 = tic;
                            data(trl,:) = [now 100 1 0 0 Par.ICMS];
                        elseif app.PrimingDropDown.Value == 'Y_CK'
                            show_CK(app);
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Deliver water at the end of passive
                            tm1 = tic;
                            data(trl,:) = [now 100 1 0 0 Par.ICMS];
                        else
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Deliver water at the end of passive
                            tm1 = tic;
                            data(trl,:) = [now 100 0 0 0 Par.ICMS];
                        end
                        disp('Trial: Passive');
                        iti = datasample([app.ITI1.Value:0.25:app.ITI2.Value],1);
                        step = -1;
                    end
                    
                    if step == -1 && toc(tm1) > 0.1 %this step is needed to stop the visual after, say 0.1 s
                        fprintf(licksen, 'IP');
                        show_gray(app);
                        step = -2;
                    end
                        
                    if step == -2 && toc(tm1) > 2+iti
                        step = 0;
                    end
                    
                    % Block: Normal session BUT passive interjection
                    if app.PassivesessionDropDown.Value == 'N' && step == 0 && passive == 1
                        step = 100;
                        n = 0;
                    end
                        
                    if step == 100
                        trl = trl + 1;
                        t_trl_abs(1,end+1) = 100; % Specify trl ID in trial time record
                        t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                        t100 = tic;
                        if app.PrimingDropDown.Value == 'Y'
                            show_dot(app);
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Delivery water at the end of passive
                            data(trl,:) = [now 100 1 0 0 Par.ICMS];
                        elseif app.PrimingDropDown.Value == 'Y_CK'
                            show_CK(app);
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Delivery water at the end of passive
                            data(trl,:) = [now 100 1 0 0 Par.ICMS];
                        else
                            if Par.ICMS == 1
                                stimulator.play(1);
                            end
                            % // Delivery water at the end of passive
                            data(trl,:) = [now 100 0 0 0 Par.ICMS];
                        end
                        disp('Trial: Passive')
                        iti = datasample([app.ITI1.Value:0.25:app.ITI2.Value],1);
                        n = n + 1;
                        step = 101;
                    end
                    
                    if step == 101 && toc(t100) > 0.2 %this step is needed to stop the visual after, say 0.1 s
                        fprintf(licksen, 'IP');
                        show_gray(app);
                        step = 102;
                    end
                                      
                    if step == 102 && toc(t100) > iti && n < app.InterjectofpassivesEditField.Value
                        step = 100;
                    elseif step == 102 && toc(t100) > iti && n == app.InterjectofpassivesEditField.Value
                        step = 0;
                        passive = 0;
                        t0 = tic;
                    end
                     
                    % Block: No lick period
                    if app.PassivesessionDropDown.Value == 'N' && step == 0 && passive == 0 
                        jitter = datasample([-1:0.1:1], 1);
                        if t_lick == 0 && toc(t0) > (app.NolickEditField.Value + jitter)
                            step = 1;
                        elseif t_lick > 0 && (toc(t0) - t_lick > (app.NolickEditField.Value) + jitter)
                            step = 1;
                        end
                    end
                    %%
                    
                    % Block: Stimulus delivery
                    if step == 1 
                        
                        % Making the mini-block of 4
                        if id_i == 4
                            id_m = [1 1 0 0];
                            id_m = id_m(randperm(length(id_m)));
                            id_i = 0;
                        end
                        id_i = id_i+1;
                        id_trl = id_m(id_i);
                        %%
                        
                    
                        if id_trl == 1 % if this is a stim trial
                            
                            trl = trl + 1;
                            
                            t_trl_abs(1,end+1) = 1; % Specify trl ID in trial time record
                            t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                        
                            if app.PrimingDropDown.Value == 'Y' % Visual stimulus
                                show_dot(app);
                                data(trl,:) = [now 1 1 0 0 Par.ICMS]; % Outcome defaults to Miss (0); If hit, then data(trl,4) will be set to 1.
                            elseif app.PrimingDropDown.Value == 'Y_CK'
                                show_CK(app);
                                data(trl,:) = [now 1 1 0 0 Par.ICMS]; % Outcome defaults to Miss (0); If hit, then data(trl,4) will be set to 1.
                            else
                                data(trl,:) = [now 1 0 0 0 Par.ICMS]; % Outcome defaults to Miss (0); If hit, then data(trl,4) will be set to 1.
                            end
                            
                            if Par.ICMS == 1 % true if CereStim is ready AND stim Yes
%                                dasbit(0,1);
                                stimulator.play(1);
                            end
                            t_sti = tic;
                            
                            if app.PasswTSDropDown.Value == 'N' % If this is a passive_with_trialStr trial? Or a real trial
                                fprintf(licksen, 'IE1');
                                fprintf(licksen, 'IS');
                            else
                                data(trl,2) = 101;
                            end
                            
                            disp('Trial: Stimulation'); 
                            step = 1.5;
                            
                        else % no-stim trial
                            
                            t_sti = tic;
                            fprintf(licksen, 'IS');
                            trl = trl + 1;
                            
                            t_trl_abs(1,end+1) = 0; % Specify trl ID in trial time record
                            t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                            
                            fprintf(licksen, 'IZ'); % Open the sham valve for fake sound
                            
                            if app.PrimingDropDown.Value == 'Y' % No priming but just to log the condition correctly
                                data(trl,:) = [now 0 1 1 0 Par.ICMS]; % Default to success/CR (1); if there's an error (FA) then this will be set to (0)
                            else
                                data(trl,:) = [now 0 0 1 0 Par.ICMS]; % Default to success/CR (1); if there's an error (FA) then this will be set to (0)
                            end                            
                            disp('Trial: No stimulation');
                            step = 2;
                            
                        end  
                        
                        iti = datasample([app.ITI1.Value:0.25:app.ITI2.Value],1);
                        
                    end
                    
                    if step == 1.5 && toc(t_sti) > 0.2 %this step is needed to stop the visual after, say 0.1 s
                        show_gray(app);
                        if app.PasswTSDropDown.Value == 'Y' % If this is a passive_with_trialStr trial?
                            fprintf(licksen, 'IP');
                        end
                        step = 2;
                    end
                    
                    if step == 2 && toc(t_sti) > (app.ResponseEditField.Value + app.RewardEditField.Value + iti)
                        if trl == trl_max
                            s1 = 'Max iteration per run (';
                            s2 = num2str(trl_max);
                            s3 = ') has reached. We will now stop and save.';
                            f = warndlg(strcat(s1,s2,s3));
                            StopButtonPushed(app);
                        elseif data(trl,2) == 0 && data(trl,4) == 0 % if FA, then timeout punishment i.e. step=-999; it doesn't matter if we add it before or after ITI since it's just one chunk of time
                            t_punBegin = tic;
                            step = -999;
%                             disp('FA timeout');
                        else
                           step = 0;
                           t_lick = 0;
                           t0 = tic;
%                            if Par.ICMS == 1
%                               dasbit(0,0);
%                            end
                        end 
                    end
                    
                    if step == -999 && toc(t_punBegin) > app.FAtimeoutEditField.Value %add timeout punishment if FA
%                         disp('end FA timeout');
                        step = 0;
                        t_lick = 0;
                        t0 = tic;
                    end
                                      
                    % Block: Performance data storage
                    if perfdata.num_trl + 1 == trl % only run the update if there's a new trl
                        perfdata.num_trl = trl; 
                        trl4cal = trl - 1; % all cal and disp below are backtracked in 1 trial!
                        if perfdata.num_trl > 1
                            perfdata.num_pas_c = sum(data(1:trl4cal,2)==100);
                            perfdata.num_sti_c = sum(data(1:trl4cal,2)==1);
                            perfdata.num_nosti_c = sum(data(1:trl4cal,2)==0);
                            perfdata.num_hit_c = sum(data(1:trl4cal,2)==1 & data(1:trl4cal,4)==1);
                            perfdata.num_miss_c = sum(data(1:trl4cal,2)==1 & data(1:trl4cal,4)==0);
                            perfdata.num_fa_c = sum(data(1:trl4cal,2)==0 & data(1:trl4cal,4)==0);
                            perfdata.num_cr_c = sum(data(1:trl4cal,2)==0 & data(1:trl4cal,4)==1);
                            perfdata.num_sti_nosti_c = perfdata.num_sti_c + perfdata.num_nosti_c;
                            % Display the numbers in the GUI fields
                            app.Text_NumTrl.Value = num2str(trl4cal);
                            app.Text_NumPas.Value = num2str(perfdata.num_pas_c);
                            app.Text_NumHit_c.Value = num2str(perfdata.num_hit_c);
                            app.Text_NumMiss_c.Value = num2str(perfdata.num_miss_c);
                            app.Text_NumCR_c.Value = num2str(perfdata.num_cr_c);
                            app.Text_NumFA_c.Value = num2str(perfdata.num_fa_c);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,1) = trl4cal;
                            perfdata.log(trl4cal,2) = perfdata.num_pas_c;
                            perfdata.log(trl4cal,3) = perfdata.num_hit_c;
                            perfdata.log(trl4cal,6) = perfdata.num_fa_c;
                            perfdata.log(trl4cal,4) = perfdata.num_miss_c;
                            perfdata.log(trl4cal,5) = perfdata.num_cr_c;
                        end 
                        
                        if perfdata.num_sti_nosti_c > 9
                            perfdata.num_hit_rate_c = perfdata.num_hit_c / perfdata.num_sti_c;
                                if perfdata.num_hit_rate_c == 0
                                    perfdata.num_hit_rate_c = 0.01;
                                elseif perfdata.num_hit_rate_c == 1
                                    perfdata.num_hit_rate_c = 0.99;
                                end
                            perfdata.num_fa_rate_c = perfdata.num_fa_c / perfdata.num_nosti_c;
                                if perfdata.num_fa_rate_c == 0
                                    perfdata.num_fa_rate_c = 0.01;
                                elseif perfdata.num_fa_rate_c == 1
                                    perfdata.num_fa_rate_c = 0.99;
                                end
                            perfdata.dprime_c = norminv(perfdata.num_hit_rate_c) - norminv(perfdata.num_fa_rate_c);
                            %Display
                            app.Text_NumHR_c.Value = num2str(perfdata.num_hit_rate_c);
                            app.Text_NumFAR_c.Value = num2str(perfdata.num_fa_rate_c);
                            app.Text_NumDprime_c.Value = num2str(perfdata.dprime_c);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,7) = perfdata.num_hit_rate_c;
                            perfdata.log(trl4cal,8) = perfdata.num_fa_rate_c;
                            perfdata.log(trl4cal,9) = perfdata.dprime_c;
                            
                        end
                        
                        if  perfdata.num_sti_nosti_c > 19
                            last20_pas = sum(data(trl4cal-19:trl4cal,2)==100);
                            if last20_pas > 0
                                correction = last20_pas;                                
                            else
                                correction = 0;
                            end
                            last20_sti = sum(data(trl4cal-(19+correction):trl4cal,2)==1);
                            last20_nosti = sum(data(trl4cal-(19+correction):trl4cal,2)==0);
                            last20_hit = sum(data(trl4cal-(19+correction):trl4cal,2)==1 & data(trl4cal-(19+correction):trl4cal,4)==1);
                            last20_fa = sum(data(trl4cal-(19+correction):trl4cal,2)==0 & data(trl4cal-(19+correction):trl4cal,4)==0);
                            perfdata.num_hit_rate_i = last20_hit/last20_sti;
                                if perfdata.num_hit_rate_i == 0
                                    perfdata.num_hit_rate_i = 0.01;
                                elseif perfdata.num_hit_rate_i == 1
                                    perfdata.num_hit_rate_i = 0.99;
                                end                            
                            perfdata.num_fa_rate_i = last20_fa/last20_nosti;
                                if perfdata.num_fa_rate_i == 0
                                    perfdata.num_fa_rate_i = 0.01;
                                elseif perfdata.num_fa_rate_i == 1
                                    perfdata.num_fa_rate_i = 0.99;
                                end
                            perfdata.dprime_i = norminv(perfdata.num_hit_rate_i)-norminv(perfdata.num_fa_rate_i); 
                            % Display
                            app.Text_NumHit_i.Value = num2str(last20_hit);
                            app.Text_NumMiss_i.Value= num2str(last20_sti-last20_hit);
                            app.Text_NumFA_i.Value = num2str(last20_fa);
                            app.Text_NumCR_i.Value = num2str(last20_nosti-last20_fa);
                            app.Text_NumHR_i.Value = num2str(perfdata.num_hit_rate_i);
                            app.Text_NumFAR_i.Value = num2str(perfdata.num_fa_rate_i);
                            app.Text_NumDprime_i.Value = num2str(perfdata.dprime_i);
                            % Update d-prime line in Figure 1
                            addpoints(fig1, trl4cal, perfdata.dprime_i);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,10) = last20_hit;
                            perfdata.log(trl4cal,11) = last20_sti - last20_hit;
                            perfdata.log(trl4cal,12) = last20_nosti - last20_fa;
                            perfdata.log(trl4cal,13) = last20_fa;
                            perfdata.log(trl4cal,14) = perfdata.num_hit_rate_i;
                            perfdata.log(trl4cal,15) = perfdata.num_fa_rate_i;
                            perfdata.log(trl4cal,16) = perfdata.dprime_i;
                        end
                    end
                    drawnow limitrate; %this must run every loop otherwise it lags!
                    xt8 = toc(m1);
                    time = [time; xt8];

                end


        end

        % Button pushed function: InterjectButton
        function InterjectButtonPushed(app, event)
            global passive
            passive = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global Par
            global data
            global perfdata
            global time
            global trl
            global fig1
            global wf
            global stimulator
            global t_trl_abs t_lick_abs
            
            % Stop trial run
            Par.run = 0;
            %%
            
            % Disconnect CereStim
            if Par.CsConn
                app.DiscButtonPushed;
            end
            
            %%
            
            % Prepare table for log file
            data_table = array2table(data(1:trl,:));
            data_table.Properties.VariableNames = {'DateTime', 'TrlType', 'Visual', 'Outcome', 'RT', 'ICMSavail'};
            perfdata.log = array2table(perfdata.log(1:trl,:));
            perfdata.log.Properties.VariableNames = {'TrialN','incPassive','Hit_c','Miss_c','CR_c','FA_c','HR_c','FAR_c','Dp_c','Hit_i','Miss_i','CR_i','FA_i','HR_i','FAR_i','DP_i'};
            %%
            
            % File name
            FN = char(strcat(app.SubjectTextArea.Value, '_', app.DateTextArea.Value, '_', num2str(app.SessionEditField.Value)));
            %%
            
            %JSON (path is par.savepath)
            json.version = '1.0';
            json.project = Par.dbfields.project;
            json.dataset = Par.dbfields.dataset;  
            json.date = Par.dbfields.date;
            json.subject = Par.dbfields.subject;
            json.investigator = Par.dbfields.investigator;
            json.setup = Par.dbfields.setup;
            json.condition = Par.dbfields.condition;   %not essential
            json.stimulus = Par.dbfields.stimulus; %name (str) of function
            json.logfile = [FN '_log']; %name for your logfile
            json.session = app.SessionEditField.Value;
            % json.script = thisRecord.script;
            json.display = Par.PTB;
            savejson('', json, [FN '_session.json']);
            %%
            
            % Save log (path is par.savepath)
            if Par.ICMS
                uisave({'data_table','perfdata', 'time', 'Par', 'wf', 't_trl_abs', 't_lick_abs'}, [FN '_log']);
                saveas(fig1,[FN '_fig']);
            else
                uisave({'data_table','perfdata', 'time', 'Par', 't_trl_abs', 't_lick_abs'}, [FN '_log']);
                saveas(fig1,[FN '_fig']);
            end
            %%
            
        end

        % Button pushed function: GetBLButton
        function GetBLButtonPushed(app, event)
        global Par
            fprintf(Par.comm_Lick, 'IB'); % Get current BL level
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
        global Par
            fprintf(Par.comm_Lick, strcat('IH',num2str(app.ThInput.Value))); %Set threshold
        end

        % Button pushed function: ConnButton
        function ConnButtonPushed(app, event)
            global wf
            global stimulator
            global Par
            

            % Stimulator connection
            stimulator = cerestim96();
            my_devices = stimulator.scanForDevices;
            pause(0.5);
            if isempty(my_devices)
                f = warndlg('No Cerestim found!');
                
            else
                stimulator.selectDevice(0); % 0 is the first Cerestim found by scanForDevices
                pause(0.5);
                stimulator.connect;
                pause(0.5);
                
                % Load wf parameters
                wf.id = 1;
                if app.PolarityDropDown.Value == 'CF'
                    wf.polarity = 1; %CF=1; AF=0;
                else
                    wf.polarity = 0;
                end
                wf.nPulses = str2double(app.NrpulsesEditField.Value);
                wf.currAmp = str2double(app.AmplitudeEditField.Value);
                wf.elec = str2num(app.ElectrodesEditField.Value);
                wf.width = str2double(app.PhasewidthEditField.Value);
                wf.ipd = str2double(app.IPDEditField.Value);
                wf.freq = str2double(app.FrequencyEditField.Value);
                
                stimulator.setStimPattern('waveform',wf.id,...
                'polarity',wf.polarity,...
                'pulses',wf.nPulses,...
                'amp1',wf.currAmp,...
                'amp2',wf.currAmp,...
                'width1',wf.width,...
                'width2',wf.width,...
                'interphase',wf.ipd,...
                'frequency',wf.freq);
                stimulator.beginSequence;
                    stimulator.beginGroup;
                        for i = size(wf.elec,2)
                            stimulator.autoStim(wf.elec(1,i),wf.id);
                        end
                    stimulator.endGroup;
                stimulator.endSequence;
                
%                 if Par.das == 0
%                     f = warndlg('No DAS card! Unable to do ICMS!');
%                     
%                 else
%                     Par.ICMS = 1; % Now it is ready to be activated by DAS bit. 
%                     app.ConnectionLamp.Color = [0 1 0];
%                 end

                    Par.CsConn = 1; % Now it is connected and ready for USB, if StimDropDown is Y
                    app.ConnectionLamp.Color = [0 1 0];

            end
                           
        end

        % Button pushed function: DiscButton
        function DiscButtonPushed(app, event)
            global stimulator
            
            x = stimulator.isConnected();
            if ~x
                disp('There is nothing to disconnect!')
            else
                stimulator.disconnect;
                app.ConnectionLamp.Color = [1 0 0];
                disp('Stimulator disconnected.')
            end
            clear x;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
           global Par
           fprintf(Par.comm_Lick, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time

        end

        % Button pushed function: UpdatestimparButton
        function UpdatestimparButtonPushed(app, event)
            global wf
            global stimulator
            global Par
            
            % Load wf parameters
                wf.id = 1;
                if app.PolarityDropDown.Value == 'CF'
                    wf.polarity = 1; %CF=1; AF=0;
                else
                    wf.polarity = 0;
                end
                wf.nPulses = str2double(app.NrpulsesEditField.Value);
                wf.currAmp = str2double(app.AmplitudeEditField.Value);
                wf.elec = str2num(app.ElectrodesEditField.Value);
                wf.width = str2double(app.PhasewidthEditField.Value);
                wf.ipd = str2double(app.IPDEditField.Value);
                wf.freq = str2double(app.FrequencyEditField.Value);
                
                stimulator.setStimPattern('waveform',wf.id,...
                'polarity',wf.polarity,...
                'pulses',wf.nPulses,...
                'amp1',wf.currAmp,...
                'amp2',wf.currAmp,...
                'width1',wf.width,...
                'width2',wf.width,...
                'interphase',wf.ipd,...
                'frequency',wf.freq);
                stimulator.beginSequence;
                    stimulator.beginGroup;
                        for i = size(wf.elec,2)
                            stimulator.autoStim(wf.elec(1,i),wf.id);
                        end
                    stimulator.endGroup;
                stimulator.endSequence;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Trial number')
            ylabel(app.UIAxes, 'D-prime (ins.)')
            app.UIAxes.XLim = [0 300];
            app.UIAxes.YLim = [-1 3];
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            app.ICMSPanel = uipanel(app.UIFigure);
            app.ICMSPanel.Title = 'ICMS';
            app.PolarityDropDownLabel = uilabel(app.ICMSPanel);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.ICMSPanel);
            app.PolarityDropDown.Items = {'CF', 'AF'};
            app.PolarityDropDown.Value = 'CF';
            app.PhasewidthEditFieldLabel = uilabel(app.ICMSPanel);
            app.PhasewidthEditFieldLabel.Text = 'Phase width';
            app.PhasewidthEditField = uieditfield(app.ICMSPanel, 'text');
            app.PhasewidthEditField.Value = '170';
            app.AmplitudeEditFieldLabel = uilabel(app.ICMSPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ICMSPanel, 'text');
            app.AmplitudeEditField.Value = '20';
            app.FrequencyEditFieldLabel = uilabel(app.ICMSPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ICMSPanel, 'text');
            app.FrequencyEditField.Value = '300';
            app.NrpulsesEditFieldLabel = uilabel(app.ICMSPanel);
            app.NrpulsesEditFieldLabel.Text = 'Nr pulses';
            app.NrpulsesEditField = uieditfield(app.ICMSPanel, 'text');
            app.NrpulsesEditField.Value = '60';
            app.sLabel_5 = uilabel(app.ICMSPanel);
            app.sLabel_5.Text = 'μs';
            app.ALabel = uilabel(app.ICMSPanel);
            app.ALabel.Text = 'μA';
            app.HzLabel = uilabel(app.ICMSPanel);
            app.HzLabel.Text = 'Hz';
            app.sLabel_6 = uilabel(app.ICMSPanel);
            app.sLabel_6.Text = 'μs';
            app.IPDEditFieldLabel = uilabel(app.ICMSPanel);
            app.IPDEditFieldLabel.Text = 'IPD';
            app.IPDEditField = uieditfield(app.ICMSPanel, 'text');
            app.IPDEditField.Value = '60';
            app.ElectrodesEditFieldLabel = uilabel(app.ICMSPanel);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.ICMSPanel, 'text');
            app.ElectrodesEditField.Value = '10';
            app.ConnButton = uibutton(app.ICMSPanel, 'push');
            app.ConnButton.ButtonPushedFcn = createCallbackFcn(app, @ConnButtonPushed, true);
            app.ConnButton.Text = 'Conn.';
            app.DiscButton = uibutton(app.ICMSPanel, 'push');
            app.DiscButton.ButtonPushedFcn = createCallbackFcn(app, @DiscButtonPushed, true);
            app.DiscButton.Text = 'Disc.';
            app.ConnectionLampLabel = uilabel(app.ICMSPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.ICMSPanel);
            app.StimDropDownLabel = uilabel(app.ICMSPanel);
            app.StimDropDownLabel.Text = 'Stim?';
            app.StimDropDown = uidropdown(app.ICMSPanel);
            app.StimDropDown.Items = {'Y', 'N'};
            app.StimDropDown.Value = 'N';
            app.UpdatestimparButton = uibutton(app.ICMSPanel, 'push');
            app.UpdatestimparButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestimparButtonPushed, true);
            app.UpdatestimparButton.Text = 'Update stim par';
            app.TrialstructurePanel = uipanel(app.UIFigure);
            app.TrialstructurePanel.Title = 'Trial structure';
            app.sLabel = uilabel(app.TrialstructurePanel);
            app.sLabel.Text = '±1 s ';
            app.sLabel_2 = uilabel(app.TrialstructurePanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.TrialstructurePanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.TrialstructurePanel);
            app.sLabel_4.Text = 's';
            app.PassivesessionDropDownLabel = uilabel(app.TrialstructurePanel);
            app.PassivesessionDropDownLabel.Text = 'Passive session?';
            app.PassivesessionDropDown = uidropdown(app.TrialstructurePanel);
            app.PassivesessionDropDown.Items = {'N', 'Y'};
            app.PassivesessionDropDown.Value = 'N';
            app.RewardEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.RewardEditFieldLabel.Text = 'Reward';
            app.RewardEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.RewardEditField.Value = 0.1;
            app.NolickEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.NolickEditFieldLabel.Text = 'No-lick';
            app.NolickEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.NolickEditField.Value = 3;
            app.ResponseEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.ResponseEditFieldLabel.Text = 'Response';
            app.ResponseEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ResponseEditField.Value = 1;
            app.ITIEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITI1 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI1.Value = 3;
            app.toLabel = uilabel(app.TrialstructurePanel);
            app.toLabel.Text = 'to';
            app.ITI2 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI2.Value = 5;
            app.SetButton = uibutton(app.TrialstructurePanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.FAtimeoutEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.FAtimeoutEditFieldLabel.Text = 'FA timeout';
            app.FAtimeoutEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.FAtimeoutEditField.Value = 3;
            app.sLabel_7 = uilabel(app.TrialstructurePanel);
            app.sLabel_7.Text = 's';
            app.CommunicationPanel = uipanel(app.UIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.comm_Lick_lamp = uilamp(app.CommunicationPanel);
            app.comm_Lick_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Lick_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Lick_connectButtonPushed, true);
            app.comm_Lick_connect.Text = 'Connect';
            app.LickEditFieldLabel = uilabel(app.CommunicationPanel);
            app.LickEditFieldLabel.Text = 'Lick';
            app.comm_Lick_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Lick_com.Value = 'com17';
            app.comm_2pPC_lamp = uilamp(app.CommunicationPanel);
            app.comm_2pPC_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_2pPC_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_2pPC_connectButtonPushed, true);
            app.comm_2pPC_connect.Text = 'Connect';
            app.pPCEditFieldLabel = uilabel(app.CommunicationPanel);
            app.pPCEditFieldLabel.Text = '2p PC';
            app.comm_2pPC_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_2pPC_com.Value = '192.87.10.153';
            app.comm_Motion_lamp = uilamp(app.CommunicationPanel);
            app.comm_Motion_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Motion_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Motion_connectPushed, true);
            app.comm_Motion_connect.Text = 'Connect';
            app.MotionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.comm_Motion_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Motion_com.Value = 'com17';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.SubjectTextAreaLabel = uilabel(app.LogPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.LogPanel);
            app.SubjectTextArea.Editable = 'off';
            app.DateTextAreaLabel = uilabel(app.LogPanel);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.LogPanel);
            app.DateTextArea.Editable = 'off';
            app.SavepathTextAreaLabel = uilabel(app.LogPanel);
            app.SavepathTextAreaLabel.Text = 'Save path';
            app.SavepathTextArea = uitextarea(app.LogPanel);
            app.SavepathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.LogPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LogPanel, 'numeric');
            app.SessionEditField.Value = 1;
            app.VisualPanel = uipanel(app.UIFigure);
            app.VisualPanel.Title = 'Visual';
            app.PrimingDropDownLabel = uilabel(app.VisualPanel);
            app.PrimingDropDownLabel.Text = 'Priming?';
            app.PrimingDropDown = uidropdown(app.VisualPanel);
            app.PrimingDropDown.Items = {'Y', 'Y_CK', 'N'};
            app.PrimingDropDown.Value = 'Y';
            app.IfYesthenLabel = uilabel(app.VisualPanel);
            app.IfYesthenLabel.Text = 'If Yes, then';
            app.ContrastEditFieldLabel = uilabel(app.VisualPanel);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.VisualPanel, 'numeric');
            app.ContrastEditField.Value = 1;
            app.DiaPxEditFieldLabel = uilabel(app.VisualPanel);
            app.DiaPxEditFieldLabel.Text = 'Dia. (Px)';
            app.DiaPxEditField = uieditfield(app.VisualPanel, 'numeric');
            app.DiaPxEditField.Value = 120;
            app.ExecutioncontrolPanel = uipanel(app.UIFigure);
            app.ExecutioncontrolPanel.Title = 'Execution control';
            app.StartButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NumberoftrialsLabel = uilabel(app.ExecutioncontrolPanel);
            app.NumberoftrialsLabel.Text = 'Number of trials:';
            app.StopButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Text_NumTrl = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumTrl.Editable = 'off';
            app.CurrentBLEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.CurrentBLEditField.Editable = 'off';
            app.GetBLButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.GetBLButton.ButtonPushedFcn = createCallbackFcn(app, @GetBLButtonPushed, true);
            app.GetBLButton.Text = 'Get BL';
            app.SetButton_2 = uibutton(app.ExecutioncontrolPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.ThInput = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.ThInput.Value = 1500;
            app.NolickEditFieldLabel_2 = uilabel(app.ExecutioncontrolPanel);
            app.NolickEditFieldLabel_2.Text = 'thres. Δ';
            app.Trialcounterupdatedelay1trialLabel = uilabel(app.ExecutioncontrolPanel);
            app.Trialcounterupdatedelay1trialLabel.Text = 'Trial counter: (update delay: 1 trial)';
            app.InterjectButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.InterjectButton.ButtonPushedFcn = createCallbackFcn(app, @InterjectButtonPushed, true);
            app.InterjectButton.Text = 'Interject';
            app.InterjectofpassivesEditFieldLabel = uilabel(app.ExecutioncontrolPanel);
            app.InterjectofpassivesEditFieldLabel.Text = 'Interject # of passives';
            app.InterjectofpassivesEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.InterjectofpassivesEditField.Value = 3;
            app.LickLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.LickLampLabel.Text = 'Lick';
            app.LickLamp = uilamp(app.ExecutioncontrolPanel);
            app.MotionLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.MotionLampLabel.Text = 'Motion';
            app.MotionLamp = uilamp(app.ExecutioncontrolPanel);
            app.incpassivesLabel = uilabel(app.ExecutioncontrolPanel);
            app.incpassivesLabel.Text = 'inc. passives:';
            app.Text_NumPas = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumPas.Editable = 'off';
            app.Performanceinstantaneouspast20trialsPanel = uipanel(app.UIFigure);
            app.Performanceinstantaneouspast20trialsPanel.Title = 'Performance - instantaneous (past 20 trials)';
            app.HLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.HLabel_2.Text = 'H';
            app.Text_NumHit_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.CRLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.CRLabel_2.Text = 'CR';
            app.Text_NumCR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.MLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.MLabel_2.Text = 'M';
            app.Text_NumMiss_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.FALabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.FALabel_2.Text = 'FA';
            app.Text_NumFA_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.HRLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.HRLabel.Text = 'HR';
            app.Text_NumHR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.FARLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.FARLabel.Text = 'FAR';
            app.Text_NumFAR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.idLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.idLabel.Text = 'id''';
            app.Text_NumDprime_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.PerformancecumulativePanel = uipanel(app.UIFigure);
            app.PerformancecumulativePanel.Title = 'Performance - cumulative';
            app.HLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.HLabel_3.Text = 'H';
            app.Text_NumHit_c = uitextarea(app.PerformancecumulativePanel);
            app.cdLabel = uilabel(app.PerformancecumulativePanel);
            app.cdLabel.Text = 'cd''';
            app.Text_NumDprime_c = uitextarea(app.PerformancecumulativePanel);
            app.HRLabel_2 = uilabel(app.PerformancecumulativePanel);
            app.HRLabel_2.Text = 'HR';
            app.Text_NumHR_c = uitextarea(app.PerformancecumulativePanel);
            app.FARLabel_2 = uilabel(app.PerformancecumulativePanel);
            app.FARLabel_2.Text = 'FAR';
            app.Text_NumFAR_c = uitextarea(app.PerformancecumulativePanel);
            app.CRLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.CRLabel_3.Text = 'CR';
            app.Text_NumCR_c = uitextarea(app.PerformancecumulativePanel);
            app.MLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.MLabel_3.Text = 'M';
            app.Text_NumMiss_c = uitextarea(app.PerformancecumulativePanel);
            app.FALabel_3 = uilabel(app.PerformancecumulativePanel);
            app.FALabel_3.Text = 'FA';
            app.Text_NumFA_c = uitextarea(app.PerformancecumulativePanel);
            app.PasswTSDropDownLabel = uilabel(app.UIFigure);
            app.PasswTSDropDownLabel.Text = 'Pass w/ TS?';
            app.PasswTSDropDown = uidropdown(app.UIFigure);
            app.PasswTSDropDown.Items = {'N', 'Y'};
            app.PasswTSDropDown.Value = 'N';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app14

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end